classdef RecordAudioVideo_playSounds < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ArduinoLedPanel                 matlab.ui.container.Panel
        ArduinoPort_EditField           matlab.ui.control.EditField
        PortEditFieldLabel              matlab.ui.control.Label
        ArduinoStatus_Label             matlab.ui.control.Label
        DisconnectArduino_Button        matlab.ui.control.Button
        ArduinoStatus_Lamp              matlab.ui.control.Lamp
        ConnectArduino_Button           matlab.ui.control.Button
        PlayVisualStimuliPanel          matlab.ui.container.Panel
        LoomingDiscButton               matlab.ui.control.Button
        post_stim_interval_sec_EditField  matlab.ui.control.NumericEditField
        PoststimintervalsLabel          matlab.ui.control.Label
        BackgroundLuminance_EditField   matlab.ui.control.NumericEditField
        Backgroundlumin01Label          matlab.ui.control.Label
        discColor_EditField_B           matlab.ui.control.NumericEditField
        discColor_EditField_G           matlab.ui.control.NumericEditField
        discColor_EditField_R           matlab.ui.control.NumericEditField
        DisccolorRGB01Label             matlab.ui.control.Label
        expansionSpeed_degps_EditField  matlab.ui.control.NumericEditField
        ExpansionspeeddegsLabel         matlab.ui.control.Label
        DiscsizeLabel                   matlab.ui.control.Label
        discSize_end_deg_EditField      matlab.ui.control.NumericEditField
        enddegLabel                     matlab.ui.control.Label
        initialdegLabel                 matlab.ui.control.Label
        discSize_init_deg_EditField     matlab.ui.control.NumericEditField
        PlaySoundsPanel                 matlab.ui.container.Panel
        GaindBLabel                     matlab.ui.control.Label
        Gain5EditField                  matlab.ui.control.NumericEditField
        Gain4EditField                  matlab.ui.control.NumericEditField
        Gain3EditField                  matlab.ui.control.NumericEditField
        Gain2EditField                  matlab.ui.control.NumericEditField
        Gain1EditField                  matlab.ui.control.NumericEditField
        Sound5Button                    matlab.ui.control.Button
        Sound4Button                    matlab.ui.control.Button
        Sound3Button                    matlab.ui.control.Button
        Sound2Button                    matlab.ui.control.Button
        Sound1Button                    matlab.ui.control.Button
        TimeElapsedEditField            matlab.ui.control.EditField
        TimeelapsedEditFieldLabel       matlab.ui.control.Label
        version_Label                   matlab.ui.control.Label
        RecordAudioVideo_playSoundsLabel  matlab.ui.control.Label
        RecordButton                    matlab.ui.control.StateButton
        StopButton                      matlab.ui.control.StateButton
        SettingsPanel                   matlab.ui.container.Panel
        MoveFLIRvideoCheckBox           matlab.ui.control.CheckBox
        AnimalID_EditField              matlab.ui.control.EditField
        AnimalIDEditFieldLabel_2        matlab.ui.control.Label
        SaveDir_EditField               matlab.ui.control.EditField
        SavedirEditFieldLabel           matlab.ui.control.Label
        ChooseSaveDir_Button            matlab.ui.control.Button
        AudioinputsPanel                matlab.ui.container.Panel
        Mic7RecordCheckBox              matlab.ui.control.CheckBox
        EphysLabel                      matlab.ui.control.Label
        Mic6RecordCheckBox              matlab.ui.control.CheckBox
        SoundsLabel                     matlab.ui.control.Label
        Mic5RecordCheckBox              matlab.ui.control.CheckBox
        FLIRLabel                       matlab.ui.control.Label
        TTLchannelsLabel                matlab.ui.control.Label
        MicrophonesLabel                matlab.ui.control.Label
        Cam4PreviewButton_2             matlab.ui.control.Button
        Mic4RecordCheckBox              matlab.ui.control.CheckBox
        Cam3PreviewButton_2             matlab.ui.control.Button
        Mic3RecordCheckBox              matlab.ui.control.CheckBox
        Cam2PreviewButton_2             matlab.ui.control.Button
        Mic2RecordCheckBox              matlab.ui.control.CheckBox
        Cam1PreviewButton_2             matlab.ui.control.Button
        Mic1RecordCheckBox              matlab.ui.control.CheckBox
        Label_8                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        CameraPanel                     matlab.ui.container.Panel
        ParallelPool_Lamp               matlab.ui.control.Lamp
        StartParallelPool_Button        matlab.ui.control.Button
        IPvideoEncoderEditField         matlab.ui.control.EditField
        IPLabel                         matlab.ui.control.Label
        Cam4Lamp                        matlab.ui.control.Lamp
        Cam3Lamp                        matlab.ui.control.Lamp
        Cam4PreviewButton               matlab.ui.control.Button
        Cam4RecordCheckBox              matlab.ui.control.CheckBox
        Cam3PreviewButton               matlab.ui.control.Button
        Cam3RecordCheckBox              matlab.ui.control.CheckBox
        Cam2Lamp                        matlab.ui.control.Lamp
        Cam1Lamp                        matlab.ui.control.Lamp
        Cam2PreviewButton               matlab.ui.control.Button
        Cam2RecordCheckBox              matlab.ui.control.CheckBox
        Cam1PreviewButton               matlab.ui.control.Button
        Cam1RecordCheckBox              matlab.ui.control.CheckBox
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        browseButtonCam                 matlab.ui.control.Button
        CamSaveDir                      matlab.ui.control.EditField
        savedirEditFieldLabel           matlab.ui.control.Label
    end

    
    properties (Access = public)
        ba      % Params and other variables, it contains ba.audio_rec and ba.sounds
        cam     % Camera object and settings
        vis     % Visual stimuli
        arduino % Serial port object of Arduino
    end
    
    methods (Access = private)
        
        function close_video_and_save_cam_timestamps(app)
            % Close video files and save cam time stamps:
            
            if isfield(app.cam, 'recordingOn') && sum(app.cam.recordingOn) > 0
                fprintf('Closing video files and saving timestamps ...\n' );
                for cx = 1 : length(app.cam.recordingOn)
                    if app.cam.recordingOn(cx) > 0
                        cancel(app.cam.F{cx})
                        % Close cam timestamps binary file
                        % N.B. fclosing the .cam binary file from here does not
                        % work, probably because it was opened in a parallel worker. This
                        % means that the file cannot be deleted or modified until Matlab is
                        % closed.
                        % About why closing files:
                        % web('https://www.mathworks.com/matlabcentral/answers/443019-fclose-what-is-the-purpose');
            %             try
            %                 fid = fopen(app.cam.TimeStamps_fullpath_bin{cx}, 'w');
            %                 fclose(fid);
            %             catch
            %                 warning(['Some error closing the cam timestamps binary file: ' app.cam.TimeStamps_filename{cx}])
            %             end
                    end
                end
                % fclose .cam binary files to be able to delete them
                % without shutting down parallel pool or matlab:
                poolObj = gcp('nocreate');
                parfor i=1:poolObj.NumWorkers
                   fclose('all');
                end
                try %#ok<TRYNC> % Keep the try statement because if F is deleted earlier, rmfield gives error.
                    app.cam = rmfield(app.cam, {'F'});
                end
                try %#ok<TRYNC> 
                    app.cam = rmfield(app.cam,'camObj');
                end
                
                disp('Converting .cam binary files for camTimeStamps into .mat...') 
                for cx = 1 : length(app.cam.recordingOn)
                    if app.cam.recordingOn(cx) > 0
                        try
                            tic_camts_loading = tic;
                            fid = fopen(app.cam.TimeStamps_fullpath_bin{cx}, 'r');
                            % Read all data from the audio file in a vector
                            camTimeStamps = fread(fid,[Inf],'double');
                            % Close the file
                            fclose(fid); clear fid
                            % Save as an independent .mat file:
                            save(app.cam.TimeStamps_fullpath_mat{cx} , 'camTimeStamps');
                            % Store within vr as well:
                            app.cam.camTimeStamps{cx} = camTimeStamps;
                            disp(['  .cam binary file for camTimeStamps ' num2str(cx) ' saved as .mat! Time elapsed: ' num2str(toc(tic_camts_loading)) ' s']) 
                        catch
                            warning(['  .cam binary file for camTimeStamps ' num2str(cx) ' failed loading or saving as .mat!']) 
                        end
                    end
                end
                cam = app.cam; %#ok<ADPROP> 
                try
                    if isfile(app.ba.data_fullpath_mat)
                        save(app.ba.data_fullpath_mat, 'cam', '-append');
                    else
                        save(app.ba.data_fullpath_mat, 'cam');
                    end
                    for cx = 1 : length(app.cam.recordingOn)
                        if app.cam.recordingOn(cx) > 0
                            % Delete .cam and .mat files for each camera
                            delete(app.cam.TimeStamps_fullpath_mat{cx});
                            delete(app.cam.TimeStamps_fullpath_bin{cx});
                        end
                    end
                    disp(['  .cam and .mat file deleted'])
                catch
                    fprintf('WARNING: Something went wrong saving the .mat data file: %s\nCam timestamps have been separately saved.\n\n', app.ba.data_fullpath_mat);
                end
            end
            %     figure;
            %     d=diff(camTimeStamps);
            %     histogram(d(2:end)); % first frame is often much slower
        end
        
        function update_parallel_pool_lamp(app)
            if ~isempty(gcp('nocreate'))
                app.ParallelPool_Lamp.Color = [0 1 0];
            else
                app.ParallelPool_Lamp.Color = [1 0 0];
            end
        end
        
        function prepare_camera_and_start_recording(app, trial_cnt)
                    %%============================
                    %% Camera settings and start recording
%                     tic_parfeval = tic;
                    %%
                    app.CamSaveDir.Value = app.ba.save_folder;
                    app.cam.saveDir      = app.CamSaveDir.Value;
                    app.cam.dateTag      = app.ba.date_str;
                    %% Create folder, get counter, close previews
                    cams_rec_val = [app.Cam1RecordCheckBox.Value,...
                                    app.Cam2RecordCheckBox.Value,...
                                    app.Cam3RecordCheckBox.Value,...
                                    app.Cam4RecordCheckBox.Value];
                    app.cam.recordingOn = cams_rec_val;
                    if sum(cams_rec_val) > 0 % if any cam recording is on
                        defaultDir = app.cam.saveDir; % this is defined in the startupFun
                        camDir     = app.CamSaveDir.Value;
                        try
                            if ~exist(camDir,'dir'), mkdir(camDir), end
                            app.cam.saveDir = camDir;
                        catch
                            warning(sprintf('\n some problem with newly entered path\n reverting back to previous path')) %#ok<SPWRN> 
                            app.CamSaveDir.Value = defaultDir;
                        end
                        % Get counter:
                        if isempty(trial_cnt)
                            video_cnt = 1;
                        else
                            video_cnt = trial_cnt;
                        end
                    end
                    for c = 1 : 4
                        try %#ok<TRYNC> 
                            closePreview(app.cam.camObj{c})
                        end
                    end
                    %%===========================
                    %% Camera settings and start recording

                    if sum(cams_rec_val) > 0 % if recording of any camera is on
                        poolObj = gcp(); % delete(gcp('nocreate'))
                        try %#ok<TRYNC> cancel possible existing running futures
                            for i = 1 : length(poolObj.FevalQueue.RunningFutures)
                                sfun = functions(poolObj.FevalQueue.RunningFutures(i).Function);
                                if contains(sfun.function, 'cam','IgnoreCase',true)
                                    cancel(poolObj.FevalQueue.RunningFutures(:))
                                end
                            end
                        end
                    
                        for cx = 1 : length(cams_rec_val)
                            if cams_rec_val(cx) > 0
                                cNr = cx;
                                video_cnt_str = num2str(video_cnt,'%03d');
                                app.cam.video_filename{cx}          = [app.ba.animalID '_' app.cam.dateTag '_' video_cnt_str '_Cam' num2str(cNr) '.avi'];
                                app.cam.TimeStamps_filename{cx}     = [app.ba.animalID '_' app.cam.dateTag '_' video_cnt_str '_Cam' num2str(cNr)  '_CamTimeStamps'];
                                app.cam.TimeStamps_fullpath_bin{cx} = fullfile(camDir, [app.cam.TimeStamps_filename{cx} '.cam']);
                                app.cam.TimeStamps_fullpath_mat{cx} = fullfile(camDir, [app.cam.TimeStamps_filename{cx} '.mat']);
                    %             vr.cam.TimeStamps_fid{cx}          = fopen(vr.cam.TimeStamps_fullpath_bin{cx},'w');
                                app.cam.F{cx} = parfeval(@Record2Cam_fwrite, 0, app.cam.camIP{cx}, app.cam.video_filename{cx}, ...
                                    [], [], app.cam.saveDir, app.cam.username, app.cam.password, app.cam.TimeStamps_fullpath_bin{cx});
                            end
                        end

                        update_parallel_pool_lamp(app);
                        tic_camera_check = tic;
                    end
                    
                    %% Check if camera is being saved
                    % It once happened that cameras were set to Record ON but no files and
                    % timestamps were acquired. So let's make sure that files are being saved
                    % before we start
                    if any(cams_rec_val)
                        disp('Checking if camera video files are being saved...')
                        for cx = find(cams_rec_val)
                            camfile_fullpath = fullfile(app.cam.saveDir, app.cam.video_filename{cx});
                            while ~isfile(camfile_fullpath) && toc(tic_camera_check) < 10 % wait up to 10 seconds  % && toc(tic_camera_check) < 2 % give enough time to create the file (if you get to this check too quickly, you might get error even though  all is fine)
                                pause(0.1)
                            end
                            if ~isfile(camfile_fullpath)
%                                 message = sprintf('Video from camera %d is not being saved even if Record is ON! \nSomething weird happened, I am aborting trial %d \nTry restarting...',cx,tr.trial_cnt);
                                message = sprintf('Video from camera %d is not being saved even if Record is ON! \nSomething weird happened in trial %d \nTrial is starting anyway...',cx,trial_cnt);
                                disp(message)
%                                 uialert(app.UIFigure, message,'Camera error!', 'Modal',true);
                                uialert(app.UIFigure, message,'Camera error!', 'Modal',false);
%                                 uiwait(app.UIFigure,5)
%                                 error(message) %#ok<SPERR>
                            end
                        end
                        disp([char(datetime('now','Format','HH:mm:ss')) ' - ...okay.'])
                    end

                    % Initiate variable for cam FLIR timestamps (currently
                    % not used):
                    app.cam.camflirTimeStamps = [];
                    app.cam.camFlir_fullpath_toMove = '';
                    app.cam.camFlir_fullpath_new    = '';

        end
        
        function prepare_audiorec_and_start_recording(app, trial_cnt)

            if any(app.ba.record_audio)
                % Get counter:
                if isempty(trial_cnt)
                    mic_cnt = 1;
                else
                    mic_cnt = trial_cnt;
                end
                mic_cnt_str = num2str(mic_cnt,'%03d');

                app.ba.audio_rec.tLastSave     = GetSecs(); % initialize timestamp, no data saved at this timestamp.
            end

            if any(app.ba.record_audio(1:4))
%                 % Get counter:
%                 if isempty(trial_cnt)
%                     mic_cnt = 1;
%                 else
%                     mic_cnt = trial_cnt;
%                 end
%                 mic_cnt_str = num2str(mic_cnt,'%03d');
                app.ba.audio_rec.filename      = [app.ba.animalID '_' app.ba.date_str '_' mic_cnt_str '_audiorec'];
                app.ba.audio_rec.fullpath_bin  = fullfile(app.ba.save_folder, [app.ba.audio_rec.filename '.mic']);
                app.ba.audio_rec.fullpath_wav  = fullfile(app.ba.save_folder, [app.ba.audio_rec.filename '.wav']);
                app.ba.audio_rec.fullpath_flac = fullfile(app.ba.save_folder, [app.ba.audio_rec.filename '.flac']);
                app.ba.audio_rec.fullpath_mat  = fullfile(app.ba.save_folder, [app.ba.audio_rec.filename '_MicTimeStamps.mat']);
                app.ba.audio_rec.fid           = fopen(app.ba.audio_rec.fullpath_bin,'w');
                % Initialize timestamps and nr.samples. These will be saved together
                % with vr variable and also in a separate file:
                app.ba.audio_rec.MicTimeStamps        = []; % timestamp of the first sample of each iteration, i.e. audiodata(1) (in GetSecs time).
                app.ba.audio_rec.MicNrSamples         = []; % nr. of samples of each iteration.
                app.ba.audio_rec.BufferOverflow       = []; % will store timestamp of iterations with buffer overflow
                app.ba.audio_rec.nAudioChannels_input_toSave = sum(app.ba.record_audio(1:4));

                % Start audio recording!
                waitForStart = 1;
                PsychPortAudio('Start', app.ba.audio_rec.devHandleRec,[],[],waitForStart);
                
                fprintf([char(datetime('now','Format','HH:mm:ss')) ' - Audio recording started! \n']);
            end


            if app.ba.record_audio(5)
                app.ba.audio_rec.ttl.filename     = [app.ba.animalID '_' app.ba.date_str '_' mic_cnt_str '_TTLcam'];
                app.ba.audio_rec.ttl.fullpath_bin  = fullfile(app.ba.save_folder, [app.ba.audio_rec.ttl.filename '.ttlcam']);
                app.ba.audio_rec.ttl.fullpath_wav  = fullfile(app.ba.save_folder, [app.ba.audio_rec.ttl.filename '.wav']);
                app.ba.audio_rec.ttl.fullpath_flac = fullfile(app.ba.save_folder, [app.ba.audio_rec.ttl.filename '.flac']);
                app.ba.audio_rec.ttl.fullpath_mat  = fullfile(app.ba.save_folder, [app.ba.audio_rec.ttl.filename '_MicTimeStamps.mat']);
                app.ba.audio_rec.ttl.fid          = fopen(app.ba.audio_rec.ttl.fullpath_bin,'w');
                % Initialize timestamps and nr.samples. These will be saved together
                % with vr variable and also in a separate file:
                app.ba.audio_rec.ttl.MicTimeStamps        = []; % timestamp of the first sample of each iteration, i.e. audiodata(1) (in GetSecs time).
                app.ba.audio_rec.ttl.MicNrSamples         = []; % nr. of samples of each iteration.
                app.ba.audio_rec.ttl.BufferOverflow       = []; % will store timestamp of iterations with buffer overflow
                app.ba.audio_rec.ttl.nAudioChannels_input_toSave = sum(app.ba.record_audio(5));
                % 
                trial_cnt_str = app.ba.trial_cnt_str;
                app.cam.camflirTimeStamps_filename     = [app.ba.animalID '_' app.ba.date_str '_' trial_cnt_str '_CamFlir1' ];
                app.cam.camflirTimeStamps_fullpath_mat = fullfile(app.cam.saveDir, [app.cam.camflirTimeStamps_filename '_CamTimeStamps.mat']);
                % Set parameters for extract_camFlir_timestamps
                app.cam.camflir.val_low  =  0; 
                app.cam.camflir.val_high =  0.02;
                app.cam.camflir.dval_min =  0.015;

                % Start audio recording!
                waitForStart = 1;
                PsychPortAudio('Start', app.ba.audio_rec.devHandleRecCam,[],[],waitForStart);
            end

            if app.ba.record_audio(6)
                app.ba.audio_rec.ttl_sound.filename     = [app.ba.animalID '_' app.ba.date_str '_' mic_cnt_str '_TTLsound' ];
                app.ba.audio_rec.ttl_sound.fullpath_bin = fullfile(app.ba.save_folder, [app.ba.audio_rec.ttl_sound.filename '.ttlsnd']);
                app.ba.audio_rec.ttl_sound.fullpath_mat = fullfile(app.ba.save_folder, [app.ba.audio_rec.ttl_sound.filename '_MicTimeStamps.mat']);
                app.ba.audio_rec.ttl_sound.fid = fopen(app.ba.audio_rec.ttl_sound.fullpath_bin,'w');

                app.ba.audio_rec.ttl_sound.MicTimeStamps     = [];
                app.ba.audio_rec.ttl_sound.MicNrSamples      = [];
                app.ba.audio_rec.ttl_sound.MicTimeStamps_TTL = [];
                app.ba.audio_rec.ttl_sound.MicSamples_TTL    = [];
                app.ba.audio_rec.ttl_sound.dValues_TTL       = [];
                app.ba.audio_rec.ttl_sound.BufferOverflow    = []; % will store timestamp of iterations with buffer overflow

                PsychPortAudio('Start', app.ba.audio_rec.devHandleRecSound);
            end

            if app.ba.record_audio(7)
                app.ba.audio_rec.ttl_ephys.filename     = [app.ba.animalID '_' app.ba.date_str '_' mic_cnt_str '_TTLephys'];
                app.ba.audio_rec.ttl_ephys.fullpath_bin  = fullfile(app.ba.save_folder, [app.ba.audio_rec.ttl_ephys.filename '.ttlephys']);
                app.ba.audio_rec.ttl_ephys.fullpath_wav  = fullfile(app.ba.save_folder, [app.ba.audio_rec.ttl_ephys.filename '.wav']);
                app.ba.audio_rec.ttl_ephys.fullpath_flac = fullfile(app.ba.save_folder, [app.ba.audio_rec.ttl_ephys.filename '.flac']);
                app.ba.audio_rec.ttl_ephys.fullpath_mat  = fullfile(app.ba.save_folder, [app.ba.audio_rec.ttl_ephys.filename '_MicTimeStamps.mat']);
                app.ba.audio_rec.ttl_ephys.fid          = fopen(app.ba.audio_rec.ttl_ephys.fullpath_bin,'w');
                % Initialize timestamps and nr.samples. These will be saved together
                % with vr variable and also in a separate file:
                app.ba.audio_rec.ttl_ephys.MicTimeStamps        = []; % timestamp of the first sample of each iteration, i.e. audiodata(1) (in GetSecs time).
                app.ba.audio_rec.ttl_ephys.MicNrSamples         = []; % nr. of samples of each iteration.
                app.ba.audio_rec.ttl_ephys.BufferOverflow       = []; % will store timestamp of iterations with buffer overflow
                app.ba.audio_rec.ttl_ephys.nAudioChannels_input_toSave = sum(app.ba.record_audio(7));
                % % 
                % trial_cnt_str = app.ba.trial_cnt_str;
                % app.cam.camflirTimeStamps_filename     = [app.ba.animalID '_' app.ba.date_str '_' trial_cnt_str '_CamFlir1' ];
                % app.cam.camflirTimeStamps_fullpath_mat = fullfile(app.cam.saveDir, [app.cam.camflirTimeStamps_filename '_CamTimeStamps.mat']);
                % % Set parameters for extract_camFlir_timestamps
                app.ba.audio_rec.ttl_ephys.val_low  =  0; 
                app.ba.audio_rec.ttl_ephys.val_high =  0.1;
                app.ba.audio_rec.ttl_ephys.dval_min =  0.05;

                % Start audio recording!
                waitForStart = 1;
                PsychPortAudio('Start', app.ba.audio_rec.devHandleRecTTLephys,[],[],waitForStart);
            end

        end
        
        function get_audio_data_and_save(app, checkInterSavingInterval)
            % With checkInterSavingInterval = 0, InterSavingInterval is
            % ignored and audio data is retrieved and saved.
            if any(app.ba.record_audio)
                currTime = GetSecs();
                if (checkInterSavingInterval && (currTime - app.ba.audio_rec.tLastSave) > app.ba.audio_rec.InterSavingInterval ) ...
                    || ~checkInterSavingInterval

                    singleType = 1; %single() type matrices only consume half as much memory as double() type matrices, without loss of audio precision for up to 24-Bit ADC hardware.

                    if any(app.ba.record_audio(1:4))
                        tic_audio_rec_saving = tic;
                        % Retrieve pending audio data from the drivers internal ringbuffer:
                        % ‘tCaptureStart’ this is an estimate of the system time (in GetSecs seconds) when the very
                        % first sample of this recording was captured by the sound input of your hardware.
                        % This is only to be trusted down to the millisecond level after former careful
                        % calibration of your setup!
                        % web('http://psychtoolbox.org/docs/PsychPortAudio-GetAudioData')
                        [audiodata, offset, overflow, tCaptureStart] = PsychPortAudio('GetAudioData', app.ba.audio_rec.devHandleRec, [],[],[],singleType);
                        % Save only the nr. input channels specified in the GUI
                        audiodata_mic = audiodata(app.ba.record_audio(1:4), :);
                        % Append audio data to binary .mic file:
                        fwrite(app.ba.audio_rec.fid, audiodata_mic, 'single');
                        % Calculate timestamp of the first sample of this iteration, i.e. audiodata(1) (in GetSecs time):
                        MicTimeStamp = tCaptureStart + (offset  / app.ba.audio_rec.SampleRate);
                        NrSamples = size(audiodata_mic, 2);
                        % Store mic timestamp of this iteration:
                        app.ba.audio_rec.MicTimeStamps  = [app.ba.audio_rec.MicTimeStamps; MicTimeStamp];
                        % Store nr. of samples of this iteration:
                        app.ba.audio_rec.MicNrSamples   = [app.ba.audio_rec.MicNrSamples; NrSamples];
                        if overflow > 0
                            % ‘overflow’ if this flag is zero then everything went fine. If it is one then you
                            % didn’t manage to call this function frequent enough, the capacity of the
                            % internal recording buffer was exceeded and therefore you lost captured sound
                            % data, i.e., there is a gap in your recording. When initially allocating the
                            % internal buffer, make sure to allocate it big enough so it is able to easily
                            % store all recorded data inbetween your calls to ‘GetAudioData’
                            % web('http://psychtoolbox.org/docs/PsychPortAudio-GetAudioData');
                            warning([char(datetime('now','Format','HH:mm:ss')) ' - Buffer overflow for audio recording! There is a gap in your recording'])
                            app.ba.audio_rec.BufferOverflow = [app.ba.audio_rec.BufferOverflow; MicTimeStamp]; 
                        end
                        toc_audio_rec_saving       = toc(tic_audio_rec_saving); %#ok<NASGU>
                        %  disp([datestr(now,'yyyy-mm-dd HH:MM:SS') ' - recorded audio data saved (it took ' num2str(toc_audio_rec_saving*1000,'%3.1f') ' msec)'])
                    end

                    app.ba.audio_rec.tLastSave = currTime;
                    
                    if app.ba.record_audio(5)
                        [audiodata_ttl, offset, overflow, tCaptureStart] = PsychPortAudio('GetAudioData', app.ba.audio_rec.devHandleRecCam, [],[],[],singleType);
                        % Downsample:
                        audiodata_ttl_down = audiodata_ttl(:, 1 : app.ba.audio_rec.ttl.downsampling : length(audiodata_ttl));
                        % Append audio data to binary .micttl file:
                        fwrite(app.ba.audio_rec.ttl.fid, audiodata_ttl_down, 'single');
                        % Calculate timestamp of the first sample of this iteration, i.e. audiodata(1) (in GetSecs time):
                        MicTimeStamp = tCaptureStart + (offset  / app.ba.audio_rec.SampleRate);
                        NrSamples_ttl = size(audiodata_ttl_down, 2);
                        % Store mic timestamp of this iteration:
                        app.ba.audio_rec.ttl.MicTimeStamps  = [app.ba.audio_rec.ttl.MicTimeStamps; MicTimeStamp];
                        % Store nr. of samples of this iteration:
                        app.ba.audio_rec.ttl.MicNrSamples   = [app.ba.audio_rec.ttl.MicNrSamples; NrSamples_ttl];
                        if overflow > 0
                            warning([char(datetime('now','Format','HH:mm:ss')) ' - Buffer overflow for audio recording! There is a gap in your recording'])
                            app.ba.audio_rec.ttl.BufferOverflow = [app.ba.audio_rec.ttl.BufferOverflow; MicTimeStamp]; 
                        end
                    end

                    if app.ba.record_audio(6)
                        [audiodata_snd, offset_snd, overflow_snd, tCaptureStart_snd] = PsychPortAudio('GetAudioData', app.ba.audio_rec.devHandleRecSound, [],[],[],singleType);
                        % Save only the nr. input channels specified in the GUI
                        audiodata_diff = diff(audiodata_snd);
                        samp_rel = find(audiodata_diff)+1;
                        vals     = double(audiodata_diff(audiodata_diff ~= 0));
                        % Calculate timestamp of the first sample of this iteration, i.e. audiodata(1) (in GetSecs time):
                        MicTimeStamp_snd = tCaptureStart_snd + (offset_snd  / app.ba.audio_rec.SampleRate);
                        NrSamples_snd    = size(audiodata_snd, 2);
                        MicTimeStamps_Sounds  = MicTimeStamp_snd + samp_rel/app.ba.audio_rec.SampleRate;
                        NrSampsBeforeThisIter = sum(app.ba.audio_rec.ttl_sound.MicNrSamples);
                        MicSamples_Sounds     = NrSampsBeforeThisIter + samp_rel;
                        data_snd_toSave = [ reshape(MicSamples_Sounds,1,[]); ...
                                            reshape(MicTimeStamps_Sounds,1,[]); ...
                                            reshape(vals,1,[]) ];
                        % Append data to binary .mic file:
                        fwrite(app.ba.audio_rec.ttl_sound.fid, data_snd_toSave, 'double');
                        % Store mic timestamp of the first sample and nr. of samples of this iteration:
                        app.ba.audio_rec.ttl_sound.MicTimeStamps  = [app.ba.audio_rec.ttl_sound.MicTimeStamps; MicTimeStamp_snd];
                        app.ba.audio_rec.ttl_sound.MicNrSamples   = [app.ba.audio_rec.ttl_sound.MicNrSamples;  NrSamples_snd];
                        % Store mic timestamp, sample number, and TTL value of all TTL changes of this iteration:
                        app.ba.audio_rec.ttl_sound.MicTimeStamps_TTL = [app.ba.audio_rec.ttl_sound.MicTimeStamps_TTL; reshape(MicTimeStamps_Sounds,[],1)];
                        app.ba.audio_rec.ttl_sound.MicSamples_TTL    = [app.ba.audio_rec.ttl_sound.MicSamples_TTL;    reshape(MicSamples_Sounds,[],1)];
                        app.ba.audio_rec.ttl_sound.dValues_TTL       = [app.ba.audio_rec.ttl_sound.dValues_TTL;       reshape(vals,[],1)];
                        if overflow_snd > 0
                            warning([char(datetime('now','Format','HH:mm:ss')) ' - Buffer overflow for audio recording (Sound TTL)! There is a gap in your recording'])
                        end
                    end

                    if app.ba.record_audio(7)
                        [audiodata_ttlephys, offset, overflow, tCaptureStart] = PsychPortAudio('GetAudioData', app.ba.audio_rec.devHandleRecTTLephys, [],[],[],singleType);
                        % Downsample:
                        audiodata_ttlephys_down = audiodata_ttlephys(:, 1 : app.ba.audio_rec.ttl_ephys.downsampling : length(audiodata_ttlephys));
                        % Append audio data to binary .micttl file:
                        fwrite(app.ba.audio_rec.ttl_ephys.fid, audiodata_ttlephys_down, 'single');
                        % Calculate timestamp of the first sample of this iteration, i.e. audiodata(1) (in GetSecs time):
                        MicTimeStamp = tCaptureStart + (offset  / app.ba.audio_rec.SampleRate);
                        NrSamples_ttlephys = size(audiodata_ttlephys_down, 2);
                        % Store mic timestamp of this iteration:
                        app.ba.audio_rec.ttl_ephys.MicTimeStamps  = [app.ba.audio_rec.ttl_ephys.MicTimeStamps; MicTimeStamp];
                        % Store nr. of samples of this iteration:
                        app.ba.audio_rec.ttl_ephys.MicNrSamples   = [app.ba.audio_rec.ttl_ephys.MicNrSamples; NrSamples_ttlephys];
                        if overflow > 0
                            warning([char(datetime('now','Format','HH:mm:ss')) ' - Buffer overflow for audio recording! There is a gap in your recording'])
                            app.ba.audio_rec.ttl_ephys.BufferOverflow = [app.ba.audio_rec.ttl_ephys.BufferOverflow; MicTimeStamp]; 
                        end
                    end


                    if app.ba.record_audio(5)
                        % Plot TTL data online. Do it here so that data for
                        % all channels are first acquired and saved.
                        % tic
                        try
                            % If the figure does not exist yet (first
                            % iteration) or has been closed, open it,
                            % otherwise just update the XData and YData
                            if isfield(app.ba.audio_rec,'figHandle') && ishandle(app.ba.audio_rec.axisHandle)
                                xvals = [1:NrSamples_ttl]/app.ba.audio_rec.ttl.SampleRate; %#ok<NBRAK1> 
                                app.ba.audio_rec.plotHandle.XData = xvals;
                                app.ba.audio_rec.plotHandle.YData = audiodata_ttl_down;
                            else
                                app.ba.audio_rec.figHandle  = figure("Name",'TTLcam','NumberTitle','off');
                                app.ba.audio_rec.axisHandle = gca;
                                cla(app.ba.audio_rec.axisHandle);
                                app.ba.audio_rec.axisHandle.XLimMode = 'manual';
                                app.ba.audio_rec.axisHandle.YLimMode = 'manual';
                                app.ba.audio_rec.axisXLim     = [0 app.ba.audio_rec.InterSavingInterval*1.2];
                                yy = app.cam.camflir.val_high*1.5;
                                app.ba.audio_rec.axisYLim     = [-yy yy];
                                xlabel('seconds'); ylabel('TTL value')
                                hold on;
                                xvals = [1:NrSamples_ttl]/app.ba.audio_rec.ttl.SampleRate; %#ok<NBRAK1> 
                                app.ba.audio_rec.plotHandle = plot(app.ba.audio_rec.axisHandle, xvals, audiodata_ttl_down);
                                xlim(app.ba.audio_rec.axisXLim);
                                ylim(app.ba.audio_rec.axisYLim);
                            end

                            drawnow;
                        catch
                        end
                        % toc
                    end
                end
            end
        end
        
        function stop_audio_recording_and_save(app, convertToWav)
            if nargin < 2
                convertToWav = 0;
            end
            
            if isfield(app.ba.audio_rec, 'figHandle')
                % Close figure online plotting
                if ishandle(app.ba.audio_rec.figHandle)
                    close(app.ba.audio_rec.figHandle)
                end
                % Delete figure handle because it causes a warning
                % displayed every time 'audio_rec' is saved and it is useless:
                app.ba.audio_rec.figHandle = [];
            end

            if any(app.ba.record_audio)
                % Save also in the general data file:
                sounds = app.ba.sounds;
                if isfile(app.ba.data_fullpath_mat)
                    save(app.ba.data_fullpath_mat, 'sounds', '-append');
                else
                    save(app.ba.data_fullpath_mat, 'sounds');
                end
            end

            if any(app.ba.record_audio(1:4))
                % Stop audio capture:
                PsychPortAudio('Stop', app.ba.audio_rec.devHandleRec);
                % Close recorded audio .mic file:
                fclose(app.ba.audio_rec.fid);
                MicTimeStamps  = app.ba.audio_rec.MicTimeStamps;
                MicNrSamples   = app.ba.audio_rec.MicNrSamples;
                BufferOverflow = app.ba.audio_rec.BufferOverflow;
                % save(app.ba.audio_rec.fullpath_mat, 'MicTimeStamps','MicNrSamples','BufferOverflow');
                % disp(['Audio recording .mic file saved!']) 
                % Further below, .mic file is converted into .wav.
                % Save also in the general data file:
                audio_rec = app.ba.audio_rec;
                try
                    if isfile(app.ba.data_fullpath_mat)
                        save(app.ba.data_fullpath_mat, 'audio_rec', '-append');
                    else
                        save(app.ba.data_fullpath_mat, 'audio_rec');
                    end
                    disp('audio_rec TimeStamps saved!');
                catch
                    fprintf('WARNING: Something went wrong saving the .mat data file: %s\nTrying to save only the microphone variables into: %s\n\n', app.ba.data_fullpath_mat, app.ba.audio_rec.fullpath_mat);
                    try %#ok<TRYNC> 
                        save(app.ba.audio_rec.fullpath_mat, 'MicTimeStamps','MicNrSamples','BufferOverflow');
                        disp('audio_rec TimeStamps saved as .mat!');
                    end
                end
            end
            
            % If the trial was longer than thr_time_convertToWav, ask if
            % you want to convert from .mic to .wav file now, because it
            % might take several minutes
            thr_time_convertToWav = 0.5*60*60; % sec
            if any(app.ba.record_audio(1:4)) && convertToWav && ...
                    app.ba.trial_te > thr_time_convertToWav
                question = sprintf(['This trial was %3.1f hours long, so converting from .mic to .wav file can take several minutes.\n'...
                'Convert now?\nIt can always be done later. Default: ''Now''\n'],...
                app.ba.trial_te/3600);
                fprintf(question);
                dlgtitle = 'Convert from .mic to .wav now?';
                btn1     = 'Now';
                btn2     = 'Skip';
                defbtn   = btn1; % default answer
                % answer = questdlg(question, dlgtitle, btn1,btn2,defbtn);
                % answer = choosedialog(question,dlgtitle, btn1,btn2,defbtn);
                answer = uiconfirm(app.UIFigure,question,dlgtitle,...
                                'Options',{btn1,btn2}, ...
                                'DefaultOption',defbtn,'CancelOption',defbtn,...
                                'Icon','question');
                if matches(answer, btn1)
                    convertToWav2 = true;
                else
                    convertToWav2 = false;
                    fprintf(' Conversion from .mic to .wav skipped.\n')
                end
            else
                convertToWav2 = convertToWav;
            end

            % Convert .mic file into .flac.
            if any(app.ba.record_audio(1:4)) && convertToWav2
                disp('Converting recorded audio .mic binary file into .flac...') 
                tic_wav_conversion = tic;
                fid = fopen(app.ba.audio_rec.fullpath_bin, 'r');
                % Read all data from the audio file in a vector
                recaudiodata = fread(fid,[app.ba.audio_rec.nAudioChannels_input_toSave Inf],'single=>single')';
                % Close the file
                fclose(fid);
                % Save .flac file:
                audiowrite(app.ba.audio_rec.fullpath_flac, recaudiodata, app.ba.audio_rec.SampleRate, 'BitsPerSample',24);
                disp(['  .mic file saved as .flac! Time elapsed: ' num2str(toc(tic_wav_conversion)) ' s']) 
                % Delete binary file:
                delete(app.ba.audio_rec.fullpath_bin);
                disp(['  .mic file deleted.'])
            end


            if app.ba.record_audio(5)
                % Stop audio capture:
                PsychPortAudio('Stop', app.ba.audio_rec.devHandleRecCam);
                % Close recorded audio .mic file:
                fclose(app.ba.audio_rec.ttl.fid);
                MicTimeStamps  = app.ba.audio_rec.ttl.MicTimeStamps;
                MicNrSamples   = app.ba.audio_rec.ttl.MicNrSamples;
                % save(app.ba.audio_rec.ttl.fullpath_mat, 'MicTimeStamps','MicNrSamples');
                % disp(['Audio recording TTL .ttlcam file saved!']) 
                % Further below, .micttl file is converted into .wav.
                % Save also in the general data file:
                audio_rec = app.ba.audio_rec;
                try
                    if isfile(app.ba.data_fullpath_mat)
                        save(app.ba.data_fullpath_mat, 'audio_rec', '-append');
                    else
                        save(app.ba.data_fullpath_mat, 'audio_rec');
                    end
                    disp('Audio TTL cam saved!');
                catch
                    fprintf('WARNING: Something went wrong saving the .mat data file: %s\nTrying to save only the TTL cam variables into: %s\n\n', app.ba.data_fullpath_mat, app.ba.audio_rec.ttl.fullpath_mat);
                    try %#ok<TRYNC> 
                        save(app.ba.audio_rec.ttl.fullpath_mat, 'MicTimeStamps','MicNrSamples');
                        disp(['Audio recording TTL cam file saved!']) 
                    end
                end
            end
            
            % Convert .micttl file into .wav.
            if app.ba.record_audio(5) && convertToWav
                disp('Converting recorded audio TTL .ttlcam binary file into .flac...') 
                tic_wav_conversion = tic;
                fid = fopen(app.ba.audio_rec.ttl.fullpath_bin, 'r');
                % Read all data from the audio file in a vector
                recaudiodata = fread(fid,[app.ba.audio_rec.ttl.nAudioChannels_input_toSave Inf],'single=>single')';
                % Close the file
                fclose(fid);
                % Save .flac file:
                audiowrite(app.ba.audio_rec.ttl.fullpath_flac, recaudiodata, app.ba.audio_rec.ttl.SampleRate, 'BitsPerSample',24);
                disp(['  .ttlcam file saved as .flac! Time elapsed: ' num2str(toc(tic_wav_conversion)) ' s'])
                % Delete binary file:
                % Update: do not delete here because it is first needed by extract_camFlir_timestamps 
                % delete(app.ba.audio_rec.ttl.fullpath_bin);
                % disp(['  .ttlcam file deleted.'])
            end

            if app.ba.record_audio(6)
                % Stop audio capture:
                PsychPortAudio('Stop', app.ba.audio_rec.devHandleRecSound);
                % Close recorded audio .mic file:
                fclose(app.ba.audio_rec.ttl_sound.fid);
                MicTimeStamps     = app.ba.audio_rec.ttl_sound.MicTimeStamps;
                MicNrSamples      = app.ba.audio_rec.ttl_sound.MicNrSamples;
                MicTimeStamps_TTL = app.ba.audio_rec.ttl_sound.MicTimeStamps_TTL;
                MicSamples_TTL    = app.ba.audio_rec.ttl_sound.MicSamples_TTL;
                dValues_TTL       = app.ba.audio_rec.ttl_sound.dValues_TTL;
                % save(app.ba.audio_rec.ttl_sound.fullpath_mat, 'MicTimeStamps','MicNrSamples','MicTimeStamps_TTL','MicSamples_TTL','dValues_TTL');
                % disp(['Sound TTL .ttlsnd file saved!']) 
                % Save also in the general data file:
                audio_rec = app.ba.audio_rec;
                try
                    if isfile(app.ba.data_fullpath_mat)
                        save(app.ba.data_fullpath_mat, 'audio_rec', '-append');
                    else
                        save(app.ba.data_fullpath_mat, 'audio_rec');
                    end
                    disp('Sound TTL saved!');
                    % Delete the binary file .ttlsnd
                    delete(app.ba.audio_rec.ttl_sound.fullpath_bin)
                    disp('   File .ttlsnd deleted.');
                catch
                    fprintf('WARNING: Something went wrong saving the .mat data file: %s\nTrying to save only the sound TTL variables into: %s\n\n', app.ba.data_fullpath_mat, app.ba.audio_rec.ttl_sound.fullpath_mat);
                    try %#ok<TRYNC> 
                        save(app.ba.audio_rec.ttl_sound.fullpath_mat, 'MicTimeStamps','MicNrSamples','MicTimeStamps_TTL','MicSamples_TTL','dValues_TTL');
                        disp(['Sound TTL saved into .mat!']) 
                    end
                end
            end

            if app.ba.record_audio(7)
                % Stop audio capture:
                PsychPortAudio('Stop', app.ba.audio_rec.devHandleRecTTLephys);
                % Close recorded audio .mic file:
                fclose(app.ba.audio_rec.ttl_ephys.fid);
                MicTimeStamps  = app.ba.audio_rec.ttl_ephys.MicTimeStamps;
                MicNrSamples   = app.ba.audio_rec.ttl_ephys.MicNrSamples;
                % save(app.ba.audio_rec.ttl.fullpath_mat, 'MicTimeStamps','MicNrSamples');
                % disp(['Audio recording TTL .ttlcam file saved!']) 
                % Further below, .micttl file is converted into .wav.
                % Save also in the general data file:
                audio_rec = app.ba.audio_rec;
                try
                    if isfile(app.ba.data_fullpath_mat)
                        save(app.ba.data_fullpath_mat, 'audio_rec', '-append');
                    else
                        save(app.ba.data_fullpath_mat, 'audio_rec');
                    end
                    disp('Audio TTL ephys saved!');
                catch
                    fprintf('WARNING: Something went wrong saving the .mat data file: %s\nTrying to save only the TTL ephys variables into: %s\n\n', app.ba.data_fullpath_mat, app.ba.audio_rec.ttl_ephys.fullpath_mat);
                    try %#ok<TRYNC> 
                        save(app.ba.audio_rec.ttl_ephys.fullpath_mat, 'MicTimeStamps','MicNrSamples');
                        disp(['Audio recording TTL ephys file saved!']) 
                    end
                end
            end

            % Convert .micttl file into .wav.
            if app.ba.record_audio(7) && convertToWav
                disp('Converting recorded audio TTL .ttlephys binary file into .flac...') 
                tic_wav_conversion = tic;
                fid = fopen(app.ba.audio_rec.ttl_ephys.fullpath_bin, 'r');
                % Read all data from the audio file in a vector
                recaudiodata = fread(fid,[app.ba.audio_rec.ttl_ephys.nAudioChannels_input_toSave Inf],'single=>single')';
                % Close the file
                fclose(fid);
                % Save .flac file:
                audiowrite(app.ba.audio_rec.ttl_ephys.fullpath_flac, recaudiodata, app.ba.audio_rec.ttl_ephys.SampleRate, 'BitsPerSample',24);
                disp(['  .ttlephys file saved as .flac! Time elapsed: ' num2str(toc(tic_wav_conversion)) ' s'])
                % Delete binary file:
                % % Update: do not delete here because it is first needed by extract_camFlir_timestamps 
                delete(app.ba.audio_rec.ttl_ephys.fullpath_bin);
                disp(['  .ttlephys file deleted.'])
            end

        end
        

        function save_soundTimeStamps(app)
            try
                trial_cnt_str = app.ba.trial_cnt_str;
                app.ba.sounds.filename     = [app.ba.animalID '_' app.ba.date_str '_' trial_cnt_str ];
                app.ba.sounds.fullpath_mat = fullfile(app.ba.save_folder, [app.ba.sounds.filename '_SoundTimeStamps.mat']);
    
                sounds = app.ba.sounds;
                soundTimeStamps = app.ba.sounds.soundTimeStamps;
                % % Save sound timestamps in a separate .mat file:
                % soundTimeStamps = app.ba.sounds.soundTimeStamps;
                % save(app.ba.sounds.fullpath_mat, 'sounds','soundTimeStamps');
                % Save also in the general data file:
                try
                    if isfile(app.ba.data_fullpath_mat)
                        save(app.ba.data_fullpath_mat, 'sounds', '-append');
                    else
                        save(app.ba.data_fullpath_mat, 'sounds');
                    end
                    disp(['Sounds timestamps saved!']);
                catch
                    fprintf('WARNING: Something went wrong saving the .mat data file: %s\nTrying to save only the sound timestamps variables into: %s\n\n', app.ba.data_fullpath_mat, app.ba.sounds.fullpath_mat);
                    try %#ok<TRYNC> 
                        save(app.ba.sounds.fullpath_mat, 'sounds','soundTimeStamps');
                        disp(['Sound timestamps saved into .mat!']) 
                    end
                end
            catch
                fprintf('\n <!> Warning <!> \n Saving sounds timestamps failed! \n\n')
            end
        end

        function save_visTimeStamps(app)
            try
                trial_cnt_str = app.ba.trial_cnt_str;
                app.vis.filename     = [app.ba.animalID '_' app.ba.date_str '_' trial_cnt_str ];
                app.vis.fullpath_mat = fullfile(app.ba.save_folder, [app.vis.filename '_VisualTimeStamps.mat']);
    
                vis = app.vis; %#ok<ADPROP> 
                visTimeStamps = app.vis.visTimeStamps;
                % % Save sound timestamps in a separate .mat file:
                % soundTimeStamps = app.ba.sounds.soundTimeStamps;
                % save(app.ba.sounds.fullpath_mat, 'sounds','soundTimeStamps');
                % Save also in the general data file:
                try
                    if isfile(app.ba.data_fullpath_mat)
                        save(app.ba.data_fullpath_mat, 'vis', '-append');
                    else
                        save(app.ba.data_fullpath_mat, 'vis');
                    end
                    disp(['Visuals timestamps saved!']);
                catch
                    fprintf('WARNING: Something went wrong saving the .mat data file: %s\nTrying to save only the visuals timestamps variables into: %s\n\n', app.ba.data_fullpath_mat, app.vis.fullpath_mat);
                    try %#ok<TRYNC> 
                        save(app.vis.fullpath_mat, 'vis','visTimeStamps');
                        disp(['Visual timestamps saved into .mat!']) 
                    end
                end
            catch
                fprintf('\n <!> Warning <!> \n Saving visual timestamps failed! \n\n')
            end
        end

        function [sounds, audio_rec] = OpenAudioDevice( ~, record_audio )

            if nargin < 1 || isempty(record_audio)
                record_audio = false;
            end
            
            % Running on PTB-3? Abort otherwise.
            AssertOpenGL;
            % Perform basic initialization of the sound driver:
            reallyneedlowlatency = 1;
            InitializePsychSound(reallyneedlowlatency);
            
            
            % Get PsychPortAudio stuff
            dev = PsychPortAudio('getDevices');
     
            device_APInames = {dev.HostAudioAPIName}';
            device_names    = {dev.DeviceName}';
            % Devices to use, in the order specified in device_names_toUse:
            device_names_toUse = {'ASIO MADIface','Fireface UCX','Yamaha','ASIO4ALL'};
            for d = 1 : length(device_names_toUse)
                devind          = find(strcmp(device_APInames,'ASIO') & contains(device_names,device_names_toUse{d}));
                devind          = devind([dev(devind).NrOutputChannels] > 0);
                if length(devind) == 1
                    break
                end
            end
            if length(devind) == 1
                if contains(dev(devind).DeviceName, 'Fireface UCX')
                    desiredFS   = 192000; %48000
            %         selectedChannels_Fireface = [1:4; 1:4]; % Port nr. as in "Balanced Line Outputs" on the audio card
            %         selectedChannels = selectedChannels_Fireface-1; % PsychPortAudio wants # one less (it starts counting from zero)
                    % N.B. Channel selection on PsychPortAudio('Open') is not supported
                    % with this audio backend (?). So this means that you cannot
                    % specify the physical device channels for output. You can
                    % indirectly do this, because the channels are output in sequence
                    % from physical channel 1 onward.
                    selectedChannels            = []; % 
                    selectedChannels_Out        = [];
                    selectedChannels_InMic      = [1 2 3 4];
                    selectedChannels_InTTLcam   = [ 5 ];
                    selectchannels_outputToCapture = 4;
                    sounds.soundCard_name = 'Fireface UCX';
                    disp('     Fireface UCX with ASIO found')
                elseif contains(dev(devind).DeviceName, 'Yamaha')
                    % Yamaha Steinberg with ASIO is able to output 4 (or perhaps 6)
                    % channels in parallel. Seems the requested number of audio channels is not supported by this combo of hardware and sound driver.
                    % N.B. Channel selection on PsychPortAudio('Open') is not supported
                    % on this card. So this means that you cannot specify the physical
                    % device channels for output. You can indirectly do this, because
                    % the channels are output in sequence from physical channel 1
                    % onward.
                    selectedChannels            = []; % 
                    selectedChannels_Out        = [];
                    selectedChannels_InMic      = [1 2 3 4];
                    selectedChannels_InTTLcam   = [ 5 ];
                    selectchannels_outputToCapture = 4;
                    desiredFS   = 192000; %48000
                    sounds.soundCard_name = 'Yamaha Steinberg UR44';
                    disp('     Yamaha Steinberg UR44 with ASIO found')
                elseif contains(dev(devind).DeviceName, 'ASIO4ALL')
                    desiredFS   = 44100;
                    selectedChannels     = []; % 
                    selectedChannels_Out = [];
                    sounds.soundCard_name = 'Other sound card';
                    disp('     Other sound card (i.e. no Fireface or Yamaha Steinberg) with ASIO4ALL')
                elseif contains(dev(devind).DeviceName, 'ASIO MADIface')
                    selectedChannels            = []; % 
                    selectedChannels_Out        = [3, 4];
                    selectedChannels_InMic      = [9 10 11 12];
                    selectedChannels_InTTLcam   = [ 5 ];
                    selectedChannels_InTTLephys = [ 7 ];
                    selectchannels_outputToCapture = 4;
                    desiredFS   = 192000; %48000
                    sounds.soundCard_name = 'Fireface UFXII (MADIface)';
                    disp(' Fireface UFXII (MADIface) with ASIO found')
                end
                deviceID = dev(devind).DeviceIndex;
            else %if isempty(devind) || length(devind) > 1
                deviceID = 0;
                devind   = 1;
                disp(' /!\ ')
                disp(' /!\/!\ Sound card not found, improvising with deviceID = 0')
                disp([' /!\/!\ HostAudioAPIName: ' dev(devind).HostAudioAPIName ])
                disp([' /!\/!\ DeviceName: ' dev(devind).DeviceName ])
                disp(' /!\/!\/!\ ')
            end
            
            % Get PsychPortAudio ready
            try %#ok<TRYNC>
                % Delete all dynamic audio buffers:
                PsychPortAudio('DeleteBuffer');
                % Close audio device, shutdown driver:
                PsychPortAudio('Close');
            end
            
            % desiredFS = 192000; %48000 %192000
            reqlatencyclass = 1;
            if record_audio % sound playback AND recording
                modeOper       = 3+8; % 2 for recording, 3 for simultaneous capture and playback of sound; +8 to open as master device.
                % Open device with max possible nr. input channels and get 
                % audio data from all of them:
                % (Then, save only the nr. input channels specified in the
                % GUI)
                nAudioChannels_input  = 7; %4; %[nr.input] 
                nAudioChannels_output = 2; 
                % nAudioChannels is [nr.input] in case of modeOper 1
                % %[nr.output nr.input] in case of modeOper 3. 
                % N.B. 'OpenSlave' can give error if nr.output ~= nr.input,
                % so let's just set the highest for both:
%                 nAudioChannels       = [max(nAudioChannels_input, nAudioChannels_output) max(nAudioChannels_input, nAudioChannels_output)]; %[nr.input] %[nr.output nr.input] in case of modeOper 3
                nAudioChannels  =  max([selectedChannels_InMic(:); selectedChannels_InTTLcam(:); selectedChannels_InTTLcam; selectedChannels_Out(:)] );
                nAudioChannels  =  [nAudioChannels, nAudioChannels];
            else % only sound playback
                modeOper       = 1+8; % 1 for playback of sound; +8 to open as master device.
                nAudioChannels = 7; %[nr.output] 
            end
            
            
            % web('http://psychtoolbox.org/docs/PsychPortAudio-Open');
            % (1) [] = default sound device
            % (2) 1 = mode of operation: sound playback only
            % (3) 1 = reqlatencyclass: default level of latency
            % (4) Requested frequency in samples per second
            % (5) output channels (supported only for Fireface, not for Yamaha)
            sounds.devHandleMaster = PsychPortAudio('Open', deviceID, modeOper, reqlatencyclass, desiredFS, nAudioChannels, [],[],selectedChannels);
            
            % Start master immediately, wait for it to be started. We won't stop the
            % master
            PsychPortAudio('Start', sounds.devHandleMaster, 0, 0, 1);
            
            
            % Retrieve status once to get access to SampleRate and other params:
            devStatus = PsychPortAudio('GetStatus', sounds.devHandleMaster);
            
            % Add a digital tag on the other channel
            pulseLoDuration_sec  = 0.001;
            pulseHiDuration_sec  = 0.0005;
            deadTimeDuration_sec = 0.0001;
            % Store sound parameters:
            sounds.devParam.device           = dev(devind);
            sounds.devParam.modeOper         = modeOper;
            sounds.devParam.nAudioChannels   = nAudioChannels;
            sounds.devParam.desiredFS        = desiredFS;
            sounds.devParam.reqlatencyclass  = reqlatencyclass;
            sounds.devParam.selectedChannels = selectedChannels;
            sounds.SampleRate             = devStatus.SampleRate;
            sounds.PredictedOutputLatency = devStatus.PredictedLatency;
            sounds.BufferSize             = devStatus.BufferSize;
            % sounds.soundsToUse            = soundsToUse;
            sounds.pulseLoDuration_sec    = pulseLoDuration_sec;
            sounds.pulseHiDuration_sec    = pulseHiDuration_sec;
            sounds.deadTimeDuration_sec   = deadTimeDuration_sec;
            sounds.time_ptb               = [];


            % Open slave device dedicated to play sounds (modeOper = 1)
            % web('http://psychtoolbox.org/docs/PsychPortAudio-OpenSlave')
            sounds.devHandlePlay  = PsychPortAudio('OpenSlave', sounds.devHandleMaster, 1, nAudioChannels_output, selectedChannels_Out);


            %% Prepare for audio input recording and start recording
            
            if record_audio
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % Channels 1:4 for microphones
                % Open slave device dedicated to recording (modeOper = 2)
                % web('http://psychtoolbox.org/docs/PsychPortAudio-OpenSlave')
                nAudioChannels_InMic = length(selectedChannels_InMic);
                audio_rec.devHandleRec  = PsychPortAudio('OpenSlave', sounds.devHandleMaster, 2, nAudioChannels_InMic, selectedChannels_InMic);

                % Preallocate an internal audio recording buffer with a capacity of 10 seconds:
                % You must call this function once before start of capture operations to allocate
                % an internal buffer that stores captured audio data inbetween your periodic
                % calls. Provide ‘amountToAllocateSecs’ as requested buffersize in seconds. After
                % start of capture you must call this function periodically at least every
                % ‘amountToAllocateSecs’ seconds to drain the internal buffer into your matrix
                % ‘audiodata’. If you fail to call the function frequently enough, sound data will
                % get lost!
                % web('http://psychtoolbox.org/docs/PsychPortAudio-GetAudioData')
                PsychPortAudio('GetAudioData', audio_rec.devHandleRec, 10);
            
                audio_rec.InterSavingInterval  = 0.1; % sec
                audio_rec.SampleRate           = sounds.SampleRate;
                audio_rec.nAudioChannels_input = nAudioChannels_input;


                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % Channel 5 for camera FLIR TTL
                audio_rec.ttl.downsampling = 50;
                audio_rec.ttl.SampleRate   = audio_rec.SampleRate/audio_rec.ttl.downsampling;
                
                nAudioChannels_inputTTLcam = length(selectedChannels_InTTLcam);
                audio_rec.devHandleRecCam = PsychPortAudio('OpenSlave', sounds.devHandleMaster, 2, nAudioChannels_inputTTLcam, selectedChannels_InTTLcam);
                PsychPortAudio('GetAudioData', audio_rec.devHandleRecCam, 10);


                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % Channel 6 for Sound TTL

                % The slave-only mode flag 64 (kPortAudioIsOutputCapture) defines a slave capture
                % device that captures audio data from the *output channels* of the master device,
                % i.e., it records the audio stream that is sent to the speakers. This may be
                % useful for capturing PsychPortAudio’s audio output for documentation or debug
                % purposes.
                modeOperSound = 64;
                
                % selectchannels_outputToCapture = 4;
                nAudioChannels_outputToCapture = length(selectchannels_outputToCapture);
                audio_rec.devHandleRecSound = PsychPortAudio('OpenSlave', sounds.devHandleMaster, modeOperSound, nAudioChannels_outputToCapture, selectchannels_outputToCapture);
                PsychPortAudio('GetAudioData', audio_rec.devHandleRecSound, 10);


                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % Channel 7 for TTL White Matter Ephys

                audio_rec.ttl_ephys.downsampling = 100;
                audio_rec.ttl_ephys.SampleRate   = audio_rec.SampleRate/audio_rec.ttl_ephys.downsampling;
                
                nAudioChannels_InTTLephys = length(selectedChannels_InTTLephys);
                audio_rec.devHandleRecTTLephys  = PsychPortAudio('OpenSlave', sounds.devHandleMaster, 2, nAudioChannels_InTTLephys, selectedChannels_InTTLephys);
                PsychPortAudio('GetAudioData', audio_rec.devHandleRecTTLephys, 10);

            else
                audio_rec  = [];
            end
                
        end
        
        
        function sounds = prepare_sound_output(app,sounds)


            % Find sound files and load them
            folder_sounds = fullfile(fileparts( which('RecordAudioVideo_playSounds.mlapp')),'Sounds');
            
            n_sounds_tot = 5;
            sounds.n_sounds_tot = n_sounds_tot;
            sounds.wavFiles_fullpath = cell(n_sounds_tot,1);
            sounds.soundData         = cell(n_sounds_tot,1);
            sounds.soundFS           = cell(n_sounds_tot,1);
            sounds.bufferHandle      = cell(n_sounds_tot,1);
            sounds.gain              = zeros(n_sounds_tot,1);
            sounds.soundTimeStamps   = cell(n_sounds_tot,1);
            
            for s = 1 : n_sounds_tot
                ls_wavFile = dir([folder_sounds filesep 'sound' num2str(s) '*.wav']);
                if length(ls_wavFile) >= 1
                    if length(ls_wavFile) > 1
                        warning(['More than 1 sound file found starting with ''sound' num2str(s) '''. Taking the first one.']);
                    end
                    sounds.wavFiles_fullpath{s} = fullfile(folder_sounds, ls_wavFile(1).name);
                    % Get the gain from the GUI:
                    sounds.gain(s) = app.(['Gain' num2str(s) 'EditField']).Value;

                    sounds = create_sound_buffer(app, sounds, s);
                    

                else %if length(ls_wavFile) < 1
                    disp(['Warning: Sound' num2str(s) ' not found in folder ' folder_sounds]);
                    % Disable the corresponding sound button:
                    app.(['Sound' num2str(s) 'Button']).Enable = 'off';
                end
            end
            

        end

        function sounds = create_sound_buffer(~, sounds, s)                    

            desiredFS = sounds.devParam.desiredFS ;
            pulseLoDuration_sec = sounds.pulseLoDuration_sec;
            pulseHiDuration_sec = sounds.pulseHiDuration_sec;
            deadTimeDuration_sec = sounds.deadTimeDuration_sec;
            gain = sounds.gain(s);

            % Load the wave file
            [y,fs] = psychwavread(sounds.wavFiles_fullpath{s});
            if size(y,1) > size(y,2) % PTB wants a channels x samples matrix
                y = y';
            end
            % resample if necessary
            if fs ~= desiredFS
                y  = resample(y, desiredFS, fs);
                fs = desiredFS;
            end
    
            % Scale
            scaleVal = 10^(gain/20);
            y = y*scaleVal;
            if max(y)>1
                disp('Warning: may be clipping, turn down GAIN')
            end
            
            % Add a digital tag on the other channel
            loSamps   = round(pulseLoDuration_sec*fs);
            hiSamps   = round(pulseHiDuration_sec*fs);
            deadSamps = round(deadTimeDuration_sec*fs);
    
            % Put a little bit of dead time in front of sound and pulse:
            y = [zeros(1,deadSamps) y]; %#ok<*AGROW>
    
            % Make additional channels be a digital tag that carries soundID
            y2 = zeros(1,deadSamps);
            % Add a digital tag with nPulses nr. of pulses
            nPulses = s;
            for np = 1 : nPulses
                y2 = [y2 ones(1,hiSamps) zeros(1,loSamps)];
            end
            % Pad with zeros to match length of actual sound:
            endSamps = length(y) - length(y2);
            if endSamps > 0
                y2 = [y2 zeros(1,endSamps)]; % if y2 is shorter, pad y2 with zeros
            else
                y = [y zeros(1,-endSamps)]; % if y is shorter, pad y with zeros
            end
    
            % Concatenate channels to build the final signal for PTB:
            Y = [y; y2;];
    
            % Make the audioplayer object
            sounds.soundData{s}     = Y;
            sounds.soundFS{s}       = fs;
            sounds.bufferHandle{s}  = PsychPortAudio('CreateBuffer', sounds.devHandlePlay, sounds.soundData{s});
                
        end


        function move_and_rename_camFlir_videofile(app)
            
            if app.MoveFLIRvideoCheckBox.Value
                thr_vid_time = seconds(20);
    
                app_path = fileparts( which('RecordAudioVideo_playSounds.mlapp'));
                bonsai_program_folder = fullfile( app_path(1:regexp( app_path, 'Documents','end')), 'Bonsai','bonsai_record_cameraFLIR_viaArduino');
                bonsai_data_folder    = fullfile(bonsai_program_folder, 'data');
                
                try
                    camFlir_ls = dir([bonsai_data_folder filesep char(datetime(app.ba.datetime_obj, 'Format','uuuuMMdd')) '_??????_cam1.avi']);
                    CopyCSVfile = false;
                    if ~isempty(camFlir_ls)
                        camFlir_datetags = string(regexp({camFlir_ls.name}, '\d{8}_\d{6}', 'match'));
                        t = datetime(camFlir_datetags, InputFormat="yyyyMMdd_HHmmss");
                        [dt, t_min] = min(abs(t - app.ba.datetime_obj));
                        if dt < thr_vid_time
                            camFlir_fullpath_toMove = fullfile(camFlir_ls(t_min).folder, camFlir_ls(t_min).name);
                            camFlir_fullpath_new    = fullfile(app.cam.saveDir, [app.ba.animalID '_' app.cam.dateTag '_' app.ba.trial_cnt_str '_CamFlir1_' char(camFlir_datetags(t_min)) '.avi']);
                            [status,msg,msgID] = movefile(camFlir_fullpath_toMove, camFlir_fullpath_new); %#ok<ASGLU> 
                            if status
                                disp('Cam FLIR video file moved and renamed!')
                            else
                                disp('WARNING: Failed to move and rename Cam FLIR video file!')
                            end
                            disp([' from: ' camFlir_fullpath_toMove])
                            disp(['   to: ' camFlir_fullpath_new])
                            app.cam.camFlir_fullpath_toMove = camFlir_fullpath_toMove;
                            app.cam.camFlir_fullpath_new    = camFlir_fullpath_new;
                            CopyCSVfile = true;
                        else
                            disp(['Cam FLIR video file not moved because its datetime is more than ' num2str(seconds(thr_vid_time)) ' sec before/after the trial start']);
                        end
                    else
                        disp('No cam FLIR video file found.')
                    end

                catch
                    disp('WARNING: Some error while trying to move and rename the cam Flir video file')
                end

                if CopyCSVfile
                    try
                        camFlirCsv_fullpath_toMove = [camFlir_fullpath_toMove(1:end-4) '.csv'];
                        camFlirCsv_fullpath_new    = [camFlir_fullpath_new(1:end-4) '.csv'];
                        [status,msg,msgID] = copyfile(camFlirCsv_fullpath_toMove, camFlirCsv_fullpath_new); %#ok<ASGLU> 
                        if status
                            disp('  Cam FLIR csv file copied and renamed!')
                        else
                            disp('WARNING: Failed to copy and rename Cam FLIR csv file!')
                        end
                        T = readtable(camFlirCsv_fullpath_new, ...
                          'Delimiter',',');
                        for t = 1 : size(T,2)
                            app.cam.camflir.(T.Properties.VariableNames{t}) = table2array(T(:,t));
                        end
                        % Save as an independent .mat file:
                        % camflir = app.cam.camflir;
                        % save(app.cam.camflirTimeStamps_fullpath_mat, 'camflir');
                        % disp('camflirTimeStamps saved as .mat!');
                        % Save also in the general data file:
                        cam = app.cam; %#ok<ADPROP> 
                        try %#ok<TRYNC> 
                            cam = rmfield(cam,'camObj'); %#ok<ADPROP> 
                        end
                        try %#ok<TRYNC> 
                            cam = rmfield(cam,'F'); %#ok<ADPROP> 
                        end
                        try
                            if isfile(app.ba.data_fullpath_mat)
                                save(app.ba.data_fullpath_mat, 'cam', '-append');
                            else
                                save(app.ba.data_fullpath_mat, 'cam');
                            end
                            disp('  camflirTimeStamps saved!');
                            % Delete copy of .csv file
                            delete(camFlirCsv_fullpath_new)
                            disp('  Copy of Cam FLIR csv file deleted.');
                        catch
                            fprintf('WARNING: Something went wrong saving the .mat data file: %s\nTrying to save only the camflir variable into: %s\n\n', app.ba.data_fullpath_mat, app.cam.camflirTimeStamps_fullpath_mat);
                            try %#ok<TRYNC> 
                                camflir = app.cam.camflir;
                                save(app.cam.camflirTimeStamps_fullpath_mat, 'camflir');
                                disp('camflirTimeStamps saved as .mat!');
                            end
                        end
                    catch
                        disp('WARNING: Some error while trying to move and rename the cam Flir csv file')
                    end
                end
            end
            
        end
        
        function playSound_storeTimestamp(app, s)
            
            PsychPortAudio('FillBuffer', app.ba.sounds.devHandlePlay, app.ba.sounds.bufferHandle{s});
            % PsychPortAudio('Start', pahandle [, repetitions=1] [, when=0] [,
            % waitForStart=0]...
            % web('http://psychtoolbox.org/docs/PsychPortAudio-Start')
%             waitForStart = 0;
%             PsychPortAudio('Start', app.ba.sounds.devHandlePlay, 1, 0, waitForStart);
% 
%             % Store timestamp:
%             t_sound = GetSecs() + app.ba.sounds.PredictedOutputLatency;

            waitForStart = 1;
            t_sound = PsychPortAudio('Start', app.ba.sounds.devHandlePlay, 1, 0, waitForStart);
            
            try
                if app.ba.ArduinoStatus
                    write(app.arduino,'S','string')
                end
                ProblemTriggeringArduinoLed = false;
            catch
                ProblemTriggeringArduinoLed = true;
            end
            
            % Store timestamp:
            app.ba.sounds.soundTimeStamps{s}(end+1,1) = t_sound;
            
            % Print a message:
            datetime_str = char(datetime('now','Format','yyyy-MM-dd HH:mm:ss'));
            fprintf([datetime_str ' - Sound %d played \n'], s)

            if ProblemTriggeringArduinoLed
                fprintf('  <!> Problem with triggering Arduino LED <!> \n\n')
            end
        end
        
        function extract_camFlir_timestamps(app)
            try
                ttlCh = 1;
                if length(app.ba.record_audio) > 4 && app.ba.record_audio(4+ttlCh) == true
                    disp(['Extracting cam Flir timestamps from audio channel ' num2str(4+ttlCh) '...'])
    %                 tic_wav_conversion = tic;
                    fid = fopen(app.ba.audio_rec.ttl.fullpath_bin, 'r');
                    % Read all data from the audio file in a vector
                    recaudiodata = fread(fid,[app.ba.audio_rec.ttl.nAudioChannels_input_toSave Inf],'single=>single')';
                    % Close the file
                    fclose(fid);
    %                 disp(['  .mic file saved as .wav! Time elapsed: ' num2str(toc(tic_wav_conversion)) ' s']) 
                    % Reading from the wav file is much slower:
                    % [y, fs] = audioread(app.ba.audio_rec.ttl.fullpath_flac); %#ok<ASGLU> 
                    % ttl_camflir = y(:,ttlCh);
                    % clear y
                    ttl_camflir = recaudiodata(:,ttlCh);
                    clear recaudiodata
        
                    % Take a piece from the entire ttl trace, that is 3 seconds long:
                    Fs = app.ba.audio_rec.ttl.SampleRate;
                    nr_samps = Fs*3;
                    % Take this piece from the end of the trace, because in the beginning the
                    % ttl changes shape. Exclude the very last 10 second because there should be
                    % no ttl at the very end:
                    final_samps_toExclude = Fs*10;

                    if (nr_samps + final_samps_toExclude) > length(ttl_camflir)
                        % These parameters are set in
                        % prepare_audiorec_and_start_recording:
                        val_low  =  app.cam.camflir.val_low; %#ok<NASGU> 
                        val_high =  app.cam.camflir.val_high;
                        dval_min =  app.cam.camflir.dval_min;
                        val_min  = val_high;
                    else
                        ttl_piece = ttl_camflir(end-final_samps_toExclude-nr_samps+1:end-final_samps_toExclude);
                        
                        % val_low  = mean(ttl_piece(ttl_piece<0));
                        val_low  = prctile(ttl_piece(ttl_piece<0), 25);
                        val_high = mean(ttl_piece(ttl_piece>0));
                        % val_low  = -0.02;
                        % val_high =  0.01;
                        dval_min = (val_high - val_low)/2;
                        val_min  = val_high;
                        
                        app.cam.camflir.val_low  = val_low;
                        app.cam.camflir.val_high = val_high;
                        app.cam.camflir.dval_min = dval_min;
                    end

                    % Extract timestamps of camera TTLs (in microphone samples)
                    min_dist = 150; % Minimum distance of ttl onsets, in Hz (set reasonably > than actual camera frame rate)
                    % Find peak (% locs contains the timestamps of the
                    % TTL onsets (in microphone samples))
                    [pks,locs_down] = findpeaks(ttl_camflir, "MinPeakDistance",Fs/min_dist,...
                        "MinPeakProminence",dval_min, "MinPeakHeight",val_min); %#ok<ASGLU> 
                    nr_detectedFrames = length(locs_down);
    
                    MicTimeStamps  = app.ba.audio_rec.ttl.MicTimeStamps;
                    MicNrSamples   = app.ba.audio_rec.ttl.MicNrSamples;
                    camflirTimeStamps = nan(nr_detectedFrames,1);
                    for f = 1 : nr_detectedFrames
                        samp = locs_down(f); % frame onset in sound card samples
                        ind_MicNrSamples = find( (samp - MicNrSamples)> 0, 1,"first");
                        d_samps = samp - MicNrSamples(ind_MicNrSamples);
                        camflirTimeStamps(f) = MicTimeStamps(ind_MicNrSamples) + d_samps/app.ba.audio_rec.ttl.SampleRate;
                    end
                    app.cam.camflir.TimeStamps = camflirTimeStamps;
                    app.cam.camflir.MicSamples = locs_down;
                    app.cam.camflir.SampleRate = app.ba.audio_rec.ttl.SampleRate;
    
                    % Save as an independent .mat file:
                    % camflir = app.cam.camflir;
                    % save(app.cam.camflirTimeStamps_fullpath_mat, 'camflir');
                    % disp('camflirTimeStamps saved as .mat!');
                    % Save also in the general data file:
                    cam = app.cam; %#ok<ADPROP> 
                    try %#ok<TRYNC> 
                        cam = rmfield(cam,'camObj'); %#ok<ADPROP> 
                    end
                    try %#ok<TRYNC> 
                        cam = rmfield(cam,'F'); %#ok<ADPROP> 
                    end
                    try
                        if isfile(app.ba.data_fullpath_mat)
                            save(app.ba.data_fullpath_mat, 'cam', '-append');
                        else
                            save(app.ba.data_fullpath_mat, 'cam');
                        end
                        disp(['   Extracted camflirTimeStamps saved (n=' num2str(nr_detectedFrames) ')']);
                        % Delete binary file (only if corresponding .wav file exists):
                        if isfile(app.ba.audio_rec.ttl.fullpath_wav) || isfile(app.ba.audio_rec.ttl.fullpath_flac)
                            delete(app.ba.audio_rec.ttl.fullpath_bin);
                            disp(['  .ttlcam file deleted.'])
                        end
                    catch
                        fprintf('WARNING: Something went wrong saving the .mat data file: %s\nTrying to save only the camflir variables into: %s\n\n', app.ba.data_fullpath_mat, app.cam.camflirTimeStamps_fullpath_mat);
                        try %#ok<TRYNC> 
                            camflir = app.cam.camflir;
                            save(app.cam.camflirTimeStamps_fullpath_mat, 'camflir');
                            disp('camflirTimeStamps saved as .mat!');
                        end
                    end
    
                    % Check if 
                    if isfile(app.cam.camFlir_fullpath_new)
                        vid = VideoReader(app.cam.camFlir_fullpath_new);
                        if vid.NumFrames == nr_detectedFrames
                            % Yaay!
                            fprintf(' Nr. frames in video file (%d) == nr. frames extracted from TTL (audio ch,5)\n', vid.NumFrames);
                        else
                            fprintf('Warning: Nr. frames in video file is different from nr. frames extracted from TTL (audio ch,5)\n');
                            fprintf(' !-----> %d vs. %d \n', vid.NumFrames, nr_detectedFrames);
                        end
                    end
                end
            catch
                fprintf('\n <!> Warning <!> \n Extracting cam Flir timestamps from TTL audio failed! \n\n')
            end
        end


        function extract_ttlEphys_timestamps(app)
            try
                ttlCh = 3;
                if length(app.ba.record_audio) > 4 && app.ba.record_audio(4+ttlCh) == true
                    disp(['Extracting TTL ephys timestamps from audio channel ' num2str(4+ttlCh) '...']) 
    %                 tic_wav_conversion = tic;
                    fid = fopen(app.ba.audio_rec.ttl_ephys.fullpath_bin, 'r');
                    % Read all data from the audio file in a vector
                    recaudiodata = fread(fid,[app.ba.audio_rec.ttl_ephys.nAudioChannels_input_toSave Inf],'single=>single')';
                    % Close the file
                    fclose(fid);
    %                 disp(['  .mic file saved as .wav! Time elapsed: ' num2str(toc(tic_wav_conversion)) ' s']) 
                    % Reading from the wav file is much slower:
                    % [y, fs] = audioread(app.ba.audio_rec.ttl.fullpath_flac); %#ok<ASGLU> 
                    % ttl_camflir = y(:,ttlCh);
                    % clear y
                    ttl_eph = recaudiodata(:);
                    clear recaudiodata
                    
                    val_low  =  app.ba.audio_rec.ttl_ephys.val_low; %#ok<NASGU> 
                    val_high =  app.ba.audio_rec.ttl_ephys.val_high;
                    dval_min =  app.ba.audio_rec.ttl_ephys.dval_min;
                    val_min  =  val_high;
                    
                    
                    % Extract timestamps of White Matter Ephys TTLs (in microphone samples)
                    Fs = app.ba.audio_rec.ttl_ephys.SampleRate;
                    min_dist = 1; % Minimum distance of ttl onsets, in Hz
                    % Find peak (% locs contains the timestamps of the
                    % TTL onsets (in microphone samples))
                    [pks,locs_down] = findpeaks(ttl_eph, "MinPeakDistance",Fs/min_dist,...
                        "MinPeakProminence",dval_min, "MinPeakHeight",val_min); %#ok<ASGLU> 
                    nr_detectedPulses = length(locs_down);
    
                    if nr_detectedPulses < 1
                        fprintf('Warning: No TTL Ephys pulses found! \n');
                    else
                        fprintf('Nr. %d TTL Ephys pulses found.\n', nr_detectedPulses);
                    end

                    MicSamples     = locs_down;
                    MicTimeStamps  = app.ba.audio_rec.ttl_ephys.MicTimeStamps;
                    MicNrSamples   = app.ba.audio_rec.ttl_ephys.MicNrSamples;
                    ttlEphysTimeStamps = nan(nr_detectedPulses,1);
                    for f = 1 : nr_detectedPulses
                        samp = locs_down(f); % frame onset in sound card samples
                        ind_MicNrSamples = find( (samp - MicNrSamples)> 0, 1,"first");
                        d_samps = samp - MicNrSamples(ind_MicNrSamples);
                        ttlEphysTimeStamps(f) = MicTimeStamps(ind_MicNrSamples) + d_samps/app.ba.audio_rec.ttl_ephys.SampleRate;
                    end
                    app.ba.audio_rec.ttl_ephys.TimeStamps = ttlEphysTimeStamps;
                    app.ba.audio_rec.ttl_ephys.MicSamples = MicSamples;
    

                    % Save also in the general data file:
                    audio_rec = app.ba.audio_rec;
                    try
                        if isfile(app.ba.data_fullpath_mat)
                            save(app.ba.data_fullpath_mat, 'audio_rec', '-append');
                        else
                            save(app.ba.data_fullpath_mat, 'audio_rec');
                        end
                        disp('Audio TTL ephys saved!');
                    catch
                        fprintf('WARNING: Something went wrong saving the .mat data file: %s\nTrying to save only the TTL ephys variables into: %s\n\n', app.ba.data_fullpath_mat, app.ba.audio_rec.ttl_ephys.fullpath_mat);
                        try %#ok<TRYNC> 
                            save(app.ba.audio_rec.ttl_ephys.fullpath_mat, 'MicTimeStamps','MicNrSamples','MicSamples','ttlEphysTimeStamps');
                            disp(['Audio recording TTL ephys file saved!']) 
                        end
                    end

                end
            catch
                fprintf('\n <!> Warning <!> \n Extracting TTL ephys timestamps from TTL audio failed! \n\n')
            end
        end


        function save_ba(app)
            try
                ba_fieldnames = fieldnames(app.ba);
                ba_fieldnames = ba_fieldnames(~matches(ba_fieldnames,{'cam','sounds','audio_rec','vis'}));
                for f = 1 : length(ba_fieldnames)
                    settings.(ba_fieldnames{f}) = app.ba.(ba_fieldnames{f});
                end
                % Save also in the general data file:
                if isfile(app.ba.data_fullpath_mat)
                    save(app.ba.data_fullpath_mat, 'settings', '-append');
                else
                    save(app.ba.data_fullpath_mat, 'settings');
                end
            catch
                warning([' Failed saving app.ba into .mat!']) 
            end
            
        end


        function log_diary(app, Enable)
            % Capture entered commands, keyboard input, and text output
            % from the Command Window into a text file
            if Enable
                try
                    date_str_now = char(datetime('now','Format','yyyyMMdd_HHmmss'));

                    app.ba.logdiary_filename = [app.ba.animalID '_' app.ba.date_str '_' app.ba.trial_cnt_str '_LogDiary' '_' date_str_now '.txt'];
                    app.ba.logdiary_fullpath = fullfile(app.ba.save_folder, app.ba.logdiary_filename);

                    if ~isfolder(app.ba.save_folder)
                        % if it's 1st trial, folder might not exist yet and
                        % so diary gives error. So create folder now:
                        mkdir(app.ba.save_folder)
                    end
                    diary(app.ba.logdiary_fullpath)
                catch
                    warning("Diary log failed to turn on...");
                end
            else
                diary off
            end
        end
    

        function Update_LoomingDiscParameters(app)
    

            discSize_init_deg = app.discSize_init_deg_EditField.Value;
            discSize_end_deg  = app.discSize_end_deg_EditField.Value;
            expansionSpeed_degps = app.expansionSpeed_degps_EditField.Value;
   

            pixel_cm      = app.vis.screenSize_cm_horz/app.vis.screenRect(3); % calculates the size of a pixel in cm
            pixel_cm_vert = app.vis.screenSize_cm_vert/app.vis.screenRect(4); % calculates the size of a pixel in cm
            degperpix = (2*atan(pixel_cm./(2*app.vis.mouse_dist_cm))).*(180/pi);  %%true only for small angles, more generally would be: atan(pix./animal_dist_cm)  %*(180/pi) is necessary because atan in matlab gives result in radians
            pixperdeg = 1./degperpix;
            
            width_deg  = app.vis.width_px * degperpix;
            height_deg = app.vis.height_px * degperpix;
            
            % Radius and diameter of the disc (they must be integer)
            radius_init_px   = round( discSize_init_deg/2 * pixperdeg );
            discSize_init_px = radius_init_px * 2;
            
            expansionIncrement_degpf = expansionSpeed_degps * app.vis.ifi ;
            expansionIncrement_pxpf  = expansionIncrement_degpf * pixperdeg;
            
            if isempty(discSize_end_deg) || discSize_end_deg == 0
                discSize_end_deg = max([width_deg width_deg]);
            end
            discSize_end_px  = discSize_end_deg * pixperdeg;
            
            % Build a procedural disc
            discTexture = CreateProceduralSmoothedDisc(app.vis.win, discSize_init_px, discSize_init_px, [0 0 0 0], radius_init_px, app.vis.sigma, ...
                                                       app.vis.useAlpha, app.vis.smoothMethod);
            
            % Preallocate array with destination rectangles:
            texRect = Screen('Rect', discTexture);
            dstRects(:,1) = CenterRectOnPointd(texRect, app.vis.width_px/2, app.vis.height_px/2);
            
            app.vis.pixel_cm = pixel_cm;
            app.vis.pixel_cm_vert = pixel_cm_vert;
            app.vis.degperpix = degperpix;
            app.vis.pixperdeg = pixperdeg;
            app.vis.width_deg = width_deg;
            app.vis.height_deg = height_deg;
            app.vis.radius_init_px = radius_init_px;
            app.vis.discSize_init_px = discSize_init_px;
            app.vis.expansionIncrement_degpf = expansionIncrement_degpf;
            app.vis.expansionIncrement_pxpf = expansionIncrement_pxpf;
            app.vis.discSize_end_deg = discSize_end_deg;
            app.vis.discSize_end_px = discSize_end_px;
            app.vis.discTexture = discTexture;
            app.vis.texRect = texRect;
            app.vis.dstRects = dstRects;

        end


        function playLoomingDisc_storeTimestamp(app)
            
            % Disable panel:
            app.PlayVisualStimuliPanel.Enable = "off";

            win = app.vis.win;
            BackgroundLuminance = app.vis.BackgroundLuminance;
            discSize_end_deg = app.vis.discSize_end_deg;

            discSize_next_px  = app.vis.discSize_init_px;
            discSize_next_deg = app.vis.discSize_init_deg;
            discTexture = app.vis.discTexture;
            texRect = app.vis.texRect;
            dstRects = app.vis.dstRects;
            rotAngle = app.vis.rotAngle;
            discColor = app.vis.discColor;
            ifi = app.vis.ifi;
            expansionIncrement_pxpf = app.vis.expansionIncrement_pxpf;
            expansionIncrement_degpf = app.vis.expansionIncrement_degpf;
            width_px = app.vis.width_px;
            height_px = app.vis.height_px;
            post_stim_interval_sec = app.vis.post_stim_interval_sec;

            % Prepare storage of timestamps
            app.vis.visTimeStamps(end+1,:) = nan(1,3);

            % Initially sync us to VBL at start of animation loop.
            count = 0;
            Screen('FillRect', win, BackgroundLuminance);
            vbl = Screen('Flip', win);

            % Animation loop:
            while (discSize_next_deg < discSize_end_deg)
                % Step one: Batch-Draw all discs at the positions (dstRects) and
                % orientations (rotAngle) and colors (colours)
                % and with the stimulus parameters 'discParameters'
                Screen('DrawTextures', win, discTexture, [], dstRects, rotAngle, [], [], discColor);
            
                % Mark drawing ops as finished, so the GPU can do its drawing job while
                % we can compute updated parameters for next animation frame. This
                % command is not strictly needed, but it may give a slight additional
                % speedup, because the CPU can compute new stimulus parameters in
                % Matlab, while the GPU is drawing the stimuli for this frame.
                % Sometimes it helps more, sometimes less, or not at all, depending on
                % your system and code, but it only seldomly hurts.
                % performance...
%                 Screen('DrawingFinished', win);
            
                % Done. Flip one video refresh after the last 'Flip', ie. try to
                % update the display every video refresh cycle if you can.
                % This is the same as Screen('Flip', win);
                % but the provided explicit 'when' deadline allows PTB's internal
                % frame-skip detector to work more accurately and give a more
                % meaningful report of missed deadlines at the end of the script. Not
                % important for this demo, but here just in case you didn't know ;-)
                vbl = Screen('Flip', win, vbl + 0.5 * ifi);
            
                count = count + 1;
            
                % % % % % % % % % % % % % % % % % % % % % % 
                % Prepare new disc size for next iteration:
                discSize_prev_px  = discSize_next_px;
                discSize_prev_deg = discSize_next_deg;
                discSize_next_px  = discSize_prev_px  + expansionIncrement_pxpf;
                discSize_next_deg = discSize_prev_deg + expansionIncrement_degpf;
            
                scale = discSize_next_px / discSize_prev_px; %#ok<NASGU> 
            %     texRect = texRect * scale;
                texRect([3,4]) = texRect([3,4]) + expansionIncrement_pxpf;
            
                dstRects(:, 1) = CenterRectOnPointd(texRect, width_px/2, height_px/2)';
                % % % % % % % % % % % % % % % % % % % % % % 

                
                if count == 1
                    % Store timestamp of first frame of disc:
                    app.vis.visTimeStamps(end,1) = vbl;
                    % Show message:
                    datetime_str = char(datetime('now','Format','yyyy-MM-dd HH:mm:ss'));
                    fprintf([datetime_str ' - Looming disc onset \n']);
                    try
                        if app.ba.ArduinoStatus
                            write(app.arduino,'V','string')
                        end
                    catch
                        fprintf('  <!> Problem with triggering Arduino LED <!> \n\n')
                    end
                end

                get_audio_data_and_save(app,1);

            end

            % Store timestamp of disc in its final size:
            app.vis.visTimeStamps(end,2) = vbl;
            % Show message:
            datetime_str = char(datetime('now','Format','yyyy-MM-dd HH:mm:ss'));
            fprintf([datetime_str ' - Looming disc final size \n']);

            % Post-stimulus interval (keep the disc on display)
            tic_post = tic;
            while (toc(tic_post) < post_stim_interval_sec)
                % Do nothing, apart from saving audio data when necessary:
                get_audio_data_and_save(app,1);
            end
            
            Screen('FillRect', win, BackgroundLuminance);
            vbl = Screen('Flip', win);
            
            % Store timestamp of disc disappearance + background onset:
            app.vis.visTimeStamps(end,3) = vbl;
            % Show message:
            datetime_str = char(datetime('now','Format','yyyy-MM-dd HH:mm:ss'));
            fprintf([datetime_str ' - Looming disc offset \n']);

            % Re-enable panel:
            app.PlayVisualStimuliPanel.Enable = "on";
        end

        function connect_arduino(app)
            try 
                app.arduino.Port; % this line gives error if arduino has been deleted
                delete(app.arduino);
            catch
                % arduino is not connected, so nothing to delete
            end
            app.ArduinoStatus_Lamp.Color = [1 0 0];
            app.ArduinoStatus_Label.Text = 'Connecting Arduino...';
            app.ba.ArduinoStatus = 0;
            
            % Create a serial communication object on port COMx
            PortCOM = app.ArduinoPort_EditField.Value;
            app.arduino = serialport(PortCOM,115200,'DataBits',8);    
            
            Timeout = 0.1;
            app.arduino.Timeout = Timeout;
            % Set terminator to "CR/LF" to allow using Serial.println() to send message
            % from Arduino to Matlab:
            configureTerminator(app.arduino,"CR/LF"); % Serial.println() adds both a CR (\r, 13) and a LF (\n, 10)
            
            fprintf('\n\n')
            disp('Opening serial port with Arduino...')
            tic_openArdComm = tic;
            while app.arduino.NumBytesAvailable < 1
                pause(0.1)
                if toc(tic_openArdComm) > 2
%                     uialert(app.UIFigure,{['Arduino failed to connect!'];...
%                         'Arduino failed to connect',...
%                         'Icon','warning'); %#ok<*NBRAK2> 
                    app.ba.ArduinoStatus = 0;
                    fprintf('Arduino failed to connect!\n\n')
                    return
                end
            end
            while app.arduino.NumBytesAvailable > 0
                disp(readline(app.arduino))
                pause(0.1)
            end
            
            app.ba.ArduinoStatus = 1;

            app.arduino.UserData = {};
            
            % tic_start_time = tic;
%             configureCallback(app.arduino,"terminator",@(src,evt)readSerialData_v2(src,evt));
            
            fprintf('\n\n')
        end
        
        function arduino_online_lamp(app)
            try 
                app.arduino.Port; % this line gives error if arduino has been deleted
                app.ArduinoStatus_Lamp.Color = [0 1 0];
                app.ArduinoStatus_Label.Text = 'Arduino is online!';
            catch
                app.ArduinoStatus_Lamp.Color = [1 0 0];
                app.ArduinoStatus_Label.Text = 'Arduino NOT connected!';
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            fprintf('\n\n')
            fprintf('Starting RecordAudioVideo_playSounds ... \n\n')
            % Add folder (without subfolders) to Matlab path:
            addpath(fileparts( which('RecordAudioVideo_playSounds.mlapp')));
            addpath(fullfile(fileparts( which('RecordAudioVideo_playSounds.mlapp')),'SupportingFunctions'));
            
            %% Audio device
            
            % Open Audio Device:
            % force record_audio==1 because this just opens the audio 
            % device with possibilty for recording audio; 
            % the important audio recording settings are in prepare_sound_output_and_record
            record_audio = 1; % force record_audio==1 because this just opens the audio device with possibilty for recording audio; the important audio recording settings are in prepare_sound_output_and_record
            % app.ba.record_audio = vr.app.RecordAudio.Value;
            [app.ba.sounds, app.ba.audio_rec] = OpenAudioDevice( app, record_audio );
            fprintf('\n\n')
            

            app.ba.sounds = prepare_sound_output(app, app.ba.sounds);

            
            app.ba.animalID    = strtrim(app.AnimalID_EditField.Value);
            app.ba.date_str    = char(datetime('now','Format','yyyy-MM-dd'));
%             app.ba.save_folder = 'C:\Users\dailyuser\Documents\Matlab\RecordAudioVideo';
            app.ba.save_folder_root = app.SaveDir_EditField.Value;
            app.ba.save_folder      = fullfile(app.ba.save_folder_root, app.ba.animalID, app.ba.date_str);

            fprintf('\n\n')
            
            %%%%%%%%%%%%%%%%
            %% Start up cameras
            fprintf('Initializing ''orange'' cameras... \n')

            app.CamSaveDir.Value = app.ba.save_folder;
            app.cam.IPvideoEncoder = app.IPvideoEncoderEditField.Value;
%             app.cam.IPvideoEncoder = '172.22.87.108'; % Neuropixels rig
            app.cam.username = 'dailyuser';
            app.cam.password = 'zebraFINCH^@';
            app.cam.saveDir  = app.CamSaveDir.Value;
            app.cam.dateTag  = app.ba.date_str;

            for c = 1 : 4
                cNr = num2str(c);
                app.cam.Nr{c}    = cNr;
                app.cam.camIP{c} = ['http://' app.cam.IPvideoEncoder '/mjpg/', cNr, '/video.mjpg'];
                CamLamp = ['Cam' cNr 'Lamp'];
                try
                    app.cam.camObj{c} = ipcam(app.cam.camIP{c}, app.cam.username, app.cam.password);
                    app.(CamLamp).Color = [0 1 0];
                catch
                    addons = matlab.addons.installedAddons;
                    if c==1 && ~contains("MATLAB Support Package for IP Cameras",addons.Name)
                            uialert(app.ViRMEndialogUIFigure,{['You need to install MATLAB Support Package for IP Cameras'];...
                                                              ['| ! | Cameras will not work | ! |']},...
                                    'IPcam toolbox missing',...
                                    'Icon','warning');
                    end
                    app.(CamLamp).Color = [1 0 0];
                    disp(['Cam' cNr ' not found'])
                end
            end
            update_parallel_pool_lamp(app);
            fprintf('...done. \n\n')
            %%%%%%%%%%%%%%%%
            
            %%%%%%%%%%%%%%%%
            %% Visual stimuli
            fprintf('Initializing screen for visual stimuli ... \n')

            try
                Screen('CloseAll');
            catch
            end
            % % % % % % % % % % % % 
            % Hard-coded parameters
            app.vis.screenSize_cm_horz = 34.5; % cm % 34.5 cm for Vilva V156F1 
            app.vis.screenSize_cm_vert = 14.5; % cm % 14.5 cm for Vilva V156F1 (this is not important)
            app.vis.mouse_dist_cm      = 37; % cm
            % Select screen for output window:
            app.vis.screenid = max(Screen('Screens'));
            % app.vis.screenid = 2;
            % % % % % % % % % % % % 

            app.vis.discSize_init_deg    = app.discSize_init_deg_EditField.Value; %2.6; % deg
            app.vis.discSize_end_deg     = app.discSize_end_deg_EditField.Value; %47.2; % deg; if 0, it will be set as screen width
            app.vis.expansionSpeed_degps = app.expansionSpeed_degps_EditField.Value; %11.2; % deg/s, linear expansion
            % Set disc color:
            app.vis.discColor_rgb = [app.discColor_EditField_R.Value, app.discColor_EditField_G.Value, app.discColor_EditField_B.Value];
            % Set background color
            app.vis.BackgroundLuminance = app.BackgroundLuminance_EditField.Value;
            % Once disc reached final size, wait post_stim_interval_sec before
            % displaying only the background screen:
            app.vis.post_stim_interval_sec = app.post_stim_interval_sec_EditField.Value; %sec

            % Setup defaults and unit color range:
            PsychDefaultSetup(2);
            % Disable synctests for this quick demo:
            app.vis.oldSyncLevel = Screen('Preference', 'SkipSyncTests', 2); %(use 1 to continue with a warning or 2 to remove the warning completely)

            % Open a fullscreen, onscreen window with gray background. Enable 32bpc
            % floating point framebuffer via imaging pipeline on it, if this is possible
            % on your hardware while alpha-blending is enabled. Otherwise use a 16bpc
            % precision framebuffer together with alpha-blending. We need alpha-blending
            % here to implement the nice superposition of overlapping of discs. The demo will
            % abort if your graphics hardware is not capable of any of this.
            PsychImaging('PrepareConfiguration');
            PsychImaging('AddTask', 'General', 'FloatingPoint32BitIfPossible');

            [app.vis.win, app.vis.screenRect] = PsychImaging('OpenWindow', app.vis.screenid, app.vis.BackgroundLuminance);

            % Retrieve size of window in pixels:
            [app.vis.width_px, app.vis.height_px] = RectSize(app.vis.screenRect);

            % Query frame duration: We use it later on to time 'Flips' properly for an
            % animation with constant framerate:
            app.vis.ifi = Screen('GetFlipInterval', app.vis.win);

            % Enable alpha-blending
            Screen('BlendFunction', app.vis.win, 'GL_SRC_ALPHA', 'GL_ONE_MINUS_SRC_ALPHA');

            % smoothing sigma in pixel
            app.vis.sigma = 3;
            % use alpha channel for smoothing edge of disc?
            app.vis.useAlpha = true;
            % smoothing method: cosine (0), smoothstep (1), inverse smoothstep (2)
            app.vis.smoothMethod = 1;

            app.vis.rotAngle = 0;
            app.vis.myAlpha = 1; % 1 no transparency, 0 fully transparent
            app.vis.discColor = [app.vis.discColor_rgb, app.vis.myAlpha];

            Update_LoomingDiscParameters(app);

            pause(0.1);

            Screen('FillRect', app.vis.win, app.vis.BackgroundLuminance);
            Screen('Flip', app.vis.win);


            fprintf('...done. \n\n')
            %%%%%%%%%%%%%%%%


            connect_arduino(app);
            arduino_online_lamp(app);
            pause(0.1);
            % send_all_vars_to_arduino only if Arduino is connected (do not
            % do it if running the app from another PC than the arena to
            % avoid throwing many warnings)
            if app.ba.ArduinoStatus
                write(app.arduino,'0','string')
            end


            % Switch off warning for when findpeaks does not find peaks:
            app.ba.warnStruct = warning('off','signal:findpeaks:largeMinPeakHeight');
            
            fprintf('\n****************************\n')
            fprintf('   RecordAudioVideo_playSounds \n')
            fprintf('       ready !    \n')
            fprintf('****************************\n')
            
        end

        % Value changed function: RecordButton
        function RecordButtonValueChanged(app, event)
            value = app.RecordButton.Value;

            if value

                %% Disable GUI panels
                app.SettingsPanel.Enable    = "off";
                app.CameraPanel.Enable      = "off";
                app.AudioinputsPanel.Enable = "off";
                % Update app:
                drawnow;

                %% Get data folder
                animalID            = app.AnimalID_EditField.Value;
                app.ba.animalID     = strtrim(animalID);
                app.ba.date_str     = char(datetime('now','Format','yyyy-MM-dd'));
                app.ba.datetime_obj = datetime('now');
                app.ba.save_folder_root = app.SaveDir_EditField.Value;
                save_dir_curr      = fullfile(app.ba.save_folder_root, animalID, app.ba.date_str);
                app.ba.save_folder = save_dir_curr;

                %% Get incremental counter for filenames
                % Search in the data folder for previous files and get
                % counter from there, or start from 1.
                if isfolder(save_dir_curr)
                    ls{1,1} = dir([save_dir_curr filesep '*.mp4']);
                    ls{2,1} = dir([save_dir_curr filesep '*.mic']);
                    ls{3,1} = dir([save_dir_curr filesep '*.avi']);
                    ls{3,1}(contains({ls{3,1}.name},'camflir','IgnoreCase',true)) = []; % remove files with camflir that might mess up with the trial counter
                    ls{4,1} = dir([save_dir_curr filesep '*.wav?']);
                    ls{5,1} = dir([save_dir_curr filesep '*.mat']);
                    ls_filenames = [ {ls{1}(:).name}'; {ls{2}(:).name}'; {ls{3}(:).name}'; {ls{4}(:).name}'; {ls{5}(:).name}'];
                    % cnt_last = max( cellfun(@(x)str2double(x(end-6:end-4)), ls_filenames) );
                    ls_cnt_str = regexp(ls_filenames,'_\d{3}_','match','once');
                    ls_cnt_str = ls_cnt_str(~cellfun(@isempty, ls_cnt_str));
                    cnt_last = max( cellfun(@(x) str2double(x(2:4)), ls_cnt_str) );

                    trial_cnt = cnt_last + 1;
                    if isempty(trial_cnt) % no .mp4 or .mic files in the folder
                        trial_cnt = 1;
                    end
                else
                    mkdir(save_dir_curr);
                    trial_cnt = 1;
                end
                app.ba.trial_cnt     = trial_cnt;
                app.ba.trial_cnt_str = num2str(trial_cnt,'%03d');

                %% Get data .mat fullpath
                app.ba.data_filename     = [app.ba.animalID '_' app.ba.date_str '_' app.ba.trial_cnt_str '_data' ];
                app.ba.data_fullpath_mat = fullfile(app.ba.save_folder, [app.ba.data_filename '.mat']);

                %% Log diary
                log_diary(app, 1);

                %% Display screen background
                Screen('FillRect', app.vis.win, app.vis.BackgroundLuminance);
                Screen('Flip', app.vis.win);

                %% Start trial
                fprintf([char(datetime('now','Format','HH:mm:ss')) ' - Starting  trial %s \n'], app.ba.trial_cnt_str);
                

                %% Start video recording
                prepare_camera_and_start_recording(app, trial_cnt);
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


                %% Start audio input recording
                for m = 1 : app.ba.audio_rec.nAudioChannels_input
                    app.ba.record_audio(m) = app.(['Mic' num2str(m) 'RecordCheckBox']).Value;
                end
                prepare_audiorec_and_start_recording(app, trial_cnt);

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                % Reset storage for soundTimeStamps:
                app.ba.sounds.soundTimeStamps  = cell(app.ba.sounds.n_sounds_tot,1);
                % Reset storage for vidTimeStamps:
                app.vis.visTimeStamps  = [];
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                fprintf(['\n' char(datetime('now','Format','uuuu-MM-dd HH:mm:ss')) '\n Recording started!  trial %s\n\n'], app.ba.trial_cnt_str)

                
                TrialIsRunning = 1;
                tic_trial = tic;
                app.ba.tic_trial = tic_trial;

                while TrialIsRunning

                    %% Get audio data and save it:
                    get_audio_data_and_save(app, 1);
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


                    % Update Time elapsed for this trial:
                    trial_te = toc(tic_trial);
                    app.ba.trial_te = trial_te;
                    te_h = floor(trial_te / 3600);
                    te_m = floor(trial_te / 60);
                    te_s = round(rem(trial_te, 60));
                    app.TimeElapsedEditField.Value = sprintf('%02dh :%02dm : %02ds',te_h,te_m,te_s);
                    
                    % Update app (to be able to check on buttons!)
                    drawnow;

                    if app.StopButton.Value > 0 || app.RecordButton.Value < 1
                        TrialIsRunning = 0;

                        fprintf([char(datetime('now','Format','HH:mm:ss')) ' - Stopping recording... \n'])
                        try
                            %% Close video and audio capture
                            close_video_and_save_cam_timestamps(app);
                            % Audio
                            get_audio_data_and_save(app, 0);
                            stop_audio_recording_and_save(app,1)
                        catch 
                            fprintf('\n <!> Warning <!> \n Closing video or audio capture failed! \n\n')
                        end
                        % Sounds
                        save_soundTimeStamps(app);
                        % Visuals
                        save_visTimeStamps(app);
                        % Display background screen:
                        try
                            Screen('FillRect', app.vis.win, app.vis.BackgroundLuminance);
                            Screen('Flip', app.vis.win);
                        catch
                            fprintf('\n <!> Warning <!> \n Display background failed! \n\n')
                        end
                        % Extract timestamps of White Matter Ephys TTL
                        extract_ttlEphys_timestamps(app);
                        % Move and rename camFLIR video file
                        move_and_rename_camFlir_videofile(app);
                        % Extract timestamps from camera FLIR
                        extract_camFlir_timestamps(app);
                        % Save app.ba
                        save_ba(app)

                        %% Re-enable GUI panels and buttons
                        app.SettingsPanel.Enable    = "on";
                        app.CameraPanel.Enable      = "on";
                        app.AudioinputsPanel.Enable = "on";
                        app.StopButton.Value   = 0;
                        app.RecordButton.Value = 0;

                        fprintf([' All done with trial %s! \n Ready to start a new recording! \n\n\n'], app.ba.trial_cnt_str)
                        
                        log_diary(app, 0);
                    end
                end


            else % Recording has been stopped


            end
        end

        % Button pushed function: ChooseSaveDir_Button
        function ChooseSaveDir_ButtonPushed(app, event)
            try %#ok<TRYNC> 
                selpath = uigetdir(app.ba.save_folder_root,'Select root folder to save data...');
                app.SaveDir_EditField.Value = selpath;
                app.ba.save_folder_root = selpath;
            end
        end

        % Button pushed function: Cam1PreviewButton
        function Cam1PreviewButtonPushed(app, event)
            try
                preview(app.cam.camObj{1})
            catch
                disp('Cam1 not connected')
            end
        end

        % Button pushed function: Cam2PreviewButton
        function Cam2PreviewButtonPushed(app, event)
            try
                preview(app.cam.camObj{2})
            catch
                disp('Cam2 not connected')
            end
        end

        % Button pushed function: Cam3PreviewButton
        function Cam3PreviewButtonPushed(app, event)
            try
                preview(app.cam.camObj{3})
            catch
                disp('Cam3 not connected')
            end
        end

        % Button pushed function: Cam4PreviewButton
        function Cam4PreviewButtonPushed(app, event)
            try
                preview(app.cam.camObj{4})
            catch
                disp('Cam4 not connected')
            end
        end

        % Value changed function: IPvideoEncoderEditField
        function IPvideoEncoderEditFieldValueChanged(app, event)
            app.cam.IPvideoEncoder = app.IPvideoEncoderEditField.Value;
            
            while app.RecordButton.Value > 0
                pause(0.1)
            end
            for c = 1 : 4
                cNr = num2str(c);
                app.cam.Nr{c}    = cNr;
                app.cam.camIP{c} = ['http://' app.cam.IPvideoEncoder '/mjpg/', cNr, '/video.mjpg'];
                CamLamp = ['Cam' cNr 'Lamp'];
                try
                    app.cam.camObj{c} = ipcam(app.cam.camIP{c}, app.cam.username, app.cam.password);
                    app.(CamLamp).Color = [0 1 0];
                catch
                    addons = matlab.addons.installedAddons;
                    if c==1 && ~contains("MATLAB Support Package for IP Cameras",addons.Name)
                            uialert(app.ViRMEndialogUIFigure,{['You need to install MATLAB Support Package for IP Cameras'];...
                                                              ['| ! | Cameras will not work | ! |']},...
                                    'IPcam toolbox missing',...
                                    'Icon','warning'); %#ok<*NBRAK2> 
                    end
                    app.(CamLamp).Color = [1 0 0];
                    disp(['Cam' cNr ' not found'])
                end
            end
            update_parallel_pool_lamp(app);
        end

        % Button pushed function: StartParallelPool_Button
        function StartParallelPool_ButtonPushed(app, event)
            poolObj = gcp(); %#ok<NASGU> % delete(gcp('nocreate'))
            while ~exist('poolObj','var')
                pause(0.1)
            end
            update_parallel_pool_lamp(app);
        end

        % Value changed function: Gain1EditField
        function Gain1EditFieldValueChanged(app, event)
            % Get gain value from the GUI:
            s = 1;
            app.ba.sounds.gain(s) = app.(['Gain' num2str(s) 'EditField']).Value;
            % Create sound buffer:
            app.ba.sounds = create_sound_buffer(app, app.ba.sounds, s);
        end

        % Value changed function: Gain2EditField
        function Gain2EditFieldValueChanged(app, event)
            % Get gain value from the GUI:
            s = 2;
            app.ba.sounds.gain(s) = app.(['Gain' num2str(s) 'EditField']).Value;
            % Create sound buffer:
            app.ba.sounds = create_sound_buffer(app, app.ba.sounds, s);
        end

        % Value changed function: Gain3EditField
        function Gain3EditFieldValueChanged(app, event)
            % Get gain value from the GUI:
            s = 3;
            app.ba.sounds.gain(s) = app.(['Gain' num2str(s) 'EditField']).Value;
            % Create sound buffer:
            app.ba.sounds = create_sound_buffer(app, app.ba.sounds, s);
        end

        % Value changed function: Gain4EditField
        function Gain4EditFieldValueChanged(app, event)
            % Get gain value from the GUI:
            s = 4;
            app.ba.sounds.gain(s) = app.(['Gain' num2str(s) 'EditField']).Value;
            % Create sound buffer:
            app.ba.sounds = create_sound_buffer(app, app.ba.sounds, s);
        end

        % Value changed function: Gain5EditField
        function Gain5EditFieldValueChanged(app, event)
            % Get gain value from the GUI:
            s = 5;
            app.ba.sounds.gain(s) = app.(['Gain' num2str(s) 'EditField']).Value;
            % Create sound buffer:
            app.ba.sounds = create_sound_buffer(app, app.ba.sounds, s);
        end

        % Button pushed function: Sound1Button
        function Sound1ButtonPushed(app, event)
            s = 1;
            playSound_storeTimestamp(app,s);
        end

        % Button pushed function: Sound2Button
        function Sound2ButtonPushed(app, event)
            s = 2;
            playSound_storeTimestamp(app,s);
        end

        % Button pushed function: Sound3Button
        function Sound3ButtonPushed(app, event)
            s = 3;
            playSound_storeTimestamp(app,s);
        end

        % Button pushed function: Sound4Button
        function Sound4ButtonPushed(app, event)
            s = 4;
            playSound_storeTimestamp(app,s);
        end

        % Button pushed function: Sound5Button
        function Sound5ButtonPushed(app, event)
            s = 5;
            playSound_storeTimestamp(app,s);
        end

        % Value changed function: discSize_init_deg_EditField
        function discSize_init_deg_EditFieldValueChanged(app, event)
            Update_LoomingDiscParameters(app);
        end

        % Value changed function: discSize_end_deg_EditField
        function discSize_end_deg_EditFieldValueChanged(app, event)
            Update_LoomingDiscParameters(app);
        end

        % Value changed function: expansionSpeed_degps_EditField
        function expansionSpeed_degps_EditFieldValueChanged(app, event)
            Update_LoomingDiscParameters(app);
        end

        % Value changed function: discColor_EditField_R
        function discColor_EditField_RValueChanged(app, event)
            app.vis.discColor_rgb = [app.discColor_EditField_R.Value, app.discColor_EditField_G.Value, app.discColor_EditField_B.Value];
            app.vis.discColor     = [app.vis.discColor_rgb, app.vis.myAlpha];
        end

        % Value changed function: discColor_EditField_G
        function discColor_EditField_GValueChanged(app, event)
            app.vis.discColor_rgb = [app.discColor_EditField_R.Value, app.discColor_EditField_G.Value, app.discColor_EditField_B.Value];
            app.vis.discColor     = [app.vis.discColor_rgb, app.vis.myAlpha];
        end

        % Value changed function: discColor_EditField_B
        function discColor_EditField_BValueChanged(app, event)
            app.vis.discColor_rgb = [app.discColor_EditField_R.Value, app.discColor_EditField_G.Value, app.discColor_EditField_B];
            app.vis.discColor     = [app.vis.discColor_rgb, app.vis.myAlpha];
        end

        % Value changed function: BackgroundLuminance_EditField
        function BackgroundLuminance_EditFieldValueChanged(app, event)
            BackgroundLuminance = app.BackgroundLuminance_EditField.Value;
            app.vis.BackgroundLuminance = BackgroundLuminance;
            Screen('FillRect', app.vis.win, BackgroundLuminance);
            Screen('Flip', app.vis.win);
        end

        % Value changed function: post_stim_interval_sec_EditField
        function post_stim_interval_sec_EditFieldValueChanged(app, event)
            app.vis.post_stim_interval_sec = app.post_stim_interval_sec_EditField.Value; %sec
        end

        % Button pushed function: LoomingDiscButton
        function LoomingDiscButtonPushed(app, event)
            playLoomingDisc_storeTimestamp(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            try
                Screen('CloseAll');
            catch
            end

            try
                if app.ba.ArduinoStatus
                    write(app.arduino,'0','string')
                end
            catch
            end

            fprintf('\n   RecordAudioVideo_playSounds \n')
            fprintf('       closed !    \n')
            fprintf('****************************\n\n')
        end

        % Button pushed function: ConnectArduino_Button
        function ConnectArduino_ButtonPushed(app, event)
            connect_arduino(app);
            arduino_online_lamp(app);
            if app.ba.ArduinoStatus
                write(app.arduino,'0','string')
            end
        end

        % Button pushed function: DisconnectArduino_Button
        function DisconnectArduino_ButtonPushed(app, event)
            try 
                app.arduino.Port; % this line gives error if arduino has been deleted
                delete(app.arduino);
            catch
                % arduino is not connected, so nothing to delete
            end
            disp(' ')
            disp('Arduino disconnected.')
            disp(' ')
            arduino_online_lamp(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CameraPanel = uipanel(app.UIFigure);
            app.CameraPanel.Title = 'Videoencoder cameras';
            app.savedirEditFieldLabel = uilabel(app.CameraPanel);
            app.savedirEditFieldLabel.Enable = 'off';
            app.savedirEditFieldLabel.Text = 'save dir';
            app.CamSaveDir = uieditfield(app.CameraPanel, 'text');
            app.CamSaveDir.Editable = 'off';
            app.CamSaveDir.Enable = 'off';
            app.CamSaveDir.Value = 'D:\data_temp\ipcam_test\';
            app.browseButtonCam = uibutton(app.CameraPanel, 'push');
            app.browseButtonCam.Enable = 'off';
            app.browseButtonCam.Text = 'browse';
            app.Label = uilabel(app.CameraPanel);
            app.Label.Text = '1';
            app.Label_2 = uilabel(app.CameraPanel);
            app.Label_2.Text = '2';
            app.Label_3 = uilabel(app.CameraPanel);
            app.Label_3.Text = '3';
            app.Label_4 = uilabel(app.CameraPanel);
            app.Label_4.Text = '4';
            app.Cam1RecordCheckBox = uicheckbox(app.CameraPanel);
            app.Cam1RecordCheckBox.Text = 'Record';
            app.Cam1RecordCheckBox.Value = true;
            app.Cam1PreviewButton = uibutton(app.CameraPanel, 'push');
            app.Cam1PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @Cam1PreviewButtonPushed, true);
            app.Cam1PreviewButton.Text = 'View';
            app.Cam2RecordCheckBox = uicheckbox(app.CameraPanel);
            app.Cam2RecordCheckBox.Text = 'Record';
            app.Cam2PreviewButton = uibutton(app.CameraPanel, 'push');
            app.Cam2PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @Cam2PreviewButtonPushed, true);
            app.Cam2PreviewButton.Text = 'View';
            app.Cam1Lamp = uilamp(app.CameraPanel);
            app.Cam2Lamp = uilamp(app.CameraPanel);
            app.Cam3RecordCheckBox = uicheckbox(app.CameraPanel);
            app.Cam3RecordCheckBox.Text = 'Record';
            app.Cam3PreviewButton = uibutton(app.CameraPanel, 'push');
            app.Cam3PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @Cam3PreviewButtonPushed, true);
            app.Cam3PreviewButton.Text = 'View';
            app.Cam4RecordCheckBox = uicheckbox(app.CameraPanel);
            app.Cam4RecordCheckBox.Text = 'Record';
            app.Cam4PreviewButton = uibutton(app.CameraPanel, 'push');
            app.Cam4PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @Cam4PreviewButtonPushed, true);
            app.Cam4PreviewButton.Text = 'View';
            app.Cam3Lamp = uilamp(app.CameraPanel);
            app.Cam4Lamp = uilamp(app.CameraPanel);
            app.IPLabel = uilabel(app.CameraPanel);
            app.IPLabel.Text = 'IP';
            app.IPvideoEncoderEditField = uieditfield(app.CameraPanel, 'text');
            app.IPvideoEncoderEditField.ValueChangedFcn = createCallbackFcn(app, @IPvideoEncoderEditFieldValueChanged, true);
            app.IPvideoEncoderEditField.Value = '172.22.112.165';
            app.StartParallelPool_Button = uibutton(app.CameraPanel, 'push');
            app.StartParallelPool_Button.ButtonPushedFcn = createCallbackFcn(app, @StartParallelPool_ButtonPushed, true);
            app.StartParallelPool_Button.Tooltip = {'Manually start parallel pool, it takes ~10sec (if not started yet, it will start automatically when you start recording)'};
            app.StartParallelPool_Button.Text = 'Start parallel';
            app.ParallelPool_Lamp = uilamp(app.CameraPanel);
            app.AudioinputsPanel = uipanel(app.UIFigure);
            app.AudioinputsPanel.Title = 'Audio inputs';
            app.Label_5 = uilabel(app.AudioinputsPanel);
            app.Label_5.Text = '1';
            app.Label_6 = uilabel(app.AudioinputsPanel);
            app.Label_6.Text = '2';
            app.Label_7 = uilabel(app.AudioinputsPanel);
            app.Label_7.Text = '3';
            app.Label_8 = uilabel(app.AudioinputsPanel);
            app.Label_8.Text = '4';
            app.Mic1RecordCheckBox = uicheckbox(app.AudioinputsPanel);
            app.Mic1RecordCheckBox.Text = 'Record';
            app.Mic1RecordCheckBox.Value = true;
            app.Cam1PreviewButton_2 = uibutton(app.AudioinputsPanel, 'push');
            app.Cam1PreviewButton_2.Enable = 'off';
            app.Cam1PreviewButton_2.Tooltip = {'View buttons not implemented. Use OceanAudio to live view audio signal from the microphones.'};
            app.Cam1PreviewButton_2.Text = 'View';
            app.Mic2RecordCheckBox = uicheckbox(app.AudioinputsPanel);
            app.Mic2RecordCheckBox.Text = 'Record';
            app.Mic2RecordCheckBox.Value = true;
            app.Cam2PreviewButton_2 = uibutton(app.AudioinputsPanel, 'push');
            app.Cam2PreviewButton_2.Enable = 'off';
            app.Cam2PreviewButton_2.Tooltip = {'View buttons not implemented. Use OceanAudio to live view audio signal from the microphones.'};
            app.Cam2PreviewButton_2.Text = 'View';
            app.Mic3RecordCheckBox = uicheckbox(app.AudioinputsPanel);
            app.Mic3RecordCheckBox.Text = 'Record';
            app.Mic3RecordCheckBox.Value = true;
            app.Cam3PreviewButton_2 = uibutton(app.AudioinputsPanel, 'push');
            app.Cam3PreviewButton_2.Enable = 'off';
            app.Cam3PreviewButton_2.Tooltip = {'View buttons not implemented. Use OceanAudio to live view audio signal from the microphones.'};
            app.Cam3PreviewButton_2.Text = 'View';
            app.Mic4RecordCheckBox = uicheckbox(app.AudioinputsPanel);
            app.Mic4RecordCheckBox.Text = 'Record';
            app.Mic4RecordCheckBox.Value = true;
            app.Cam4PreviewButton_2 = uibutton(app.AudioinputsPanel, 'push');
            app.Cam4PreviewButton_2.Enable = 'off';
            app.Cam4PreviewButton_2.Tooltip = {'View buttons not implemented. Use OceanAudio to live view audio signal from the microphones.'};
            app.Cam4PreviewButton_2.Text = 'View';
            app.MicrophonesLabel = uilabel(app.AudioinputsPanel);
            app.MicrophonesLabel.Text = 'Microphones';
            app.TTLchannelsLabel = uilabel(app.AudioinputsPanel);
            app.TTLchannelsLabel.Text = 'TTL channels';
            app.FLIRLabel = uilabel(app.AudioinputsPanel);
            app.FLIRLabel.Text = '5 (FLIR)';
            app.Mic5RecordCheckBox = uicheckbox(app.AudioinputsPanel);
            app.Mic5RecordCheckBox.Text = 'Record';
            app.Mic5RecordCheckBox.Value = true;
            app.SoundsLabel = uilabel(app.AudioinputsPanel);
            app.SoundsLabel.Text = '6 (Sounds)';
            app.Mic6RecordCheckBox = uicheckbox(app.AudioinputsPanel);
            app.Mic6RecordCheckBox.Text = 'Record';
            app.Mic6RecordCheckBox.Value = true;
            app.EphysLabel = uilabel(app.AudioinputsPanel);
            app.EphysLabel.Text = '7 (Ephys)';
            app.Mic7RecordCheckBox = uicheckbox(app.AudioinputsPanel);
            app.Mic7RecordCheckBox.Text = 'Record';
            app.Mic7RecordCheckBox.Value = true;
            app.SettingsPanel = uipanel(app.UIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.ChooseSaveDir_Button = uibutton(app.SettingsPanel, 'push');
            app.ChooseSaveDir_Button.ButtonPushedFcn = createCallbackFcn(app, @ChooseSaveDir_ButtonPushed, true);
            app.ChooseSaveDir_Button.Text = '...';
            app.SavedirEditFieldLabel = uilabel(app.SettingsPanel);
            app.SavedirEditFieldLabel.Text = 'Save dir';
            app.SaveDir_EditField = uieditfield(app.SettingsPanel, 'text');
            app.SaveDir_EditField.Editable = 'off';
            app.SaveDir_EditField.Tooltip = {'Specify root folder to save data. Data files will be save in <AnimalID>/<Date> subfolder.'};
            app.SaveDir_EditField.Value = 'C:\Users\dailyuser\Documents\MATLAB\RecordAudioVideo_playSounds\data';
            app.AnimalIDEditFieldLabel_2 = uilabel(app.SettingsPanel);
            app.AnimalIDEditFieldLabel_2.Text = 'Animal ID';
            app.AnimalID_EditField = uieditfield(app.SettingsPanel, 'text');
            app.AnimalID_EditField.Value = 'smart_mouse';
            app.MoveFLIRvideoCheckBox = uicheckbox(app.SettingsPanel);
            app.MoveFLIRvideoCheckBox.Tooltip = {'Try to find the FLIR video file corresponding to every trial, rename it and move it from the Bonsai folder to the folder specified here.'};
            app.MoveFLIRvideoCheckBox.Text = 'Move FLIR video file';
            app.MoveFLIRvideoCheckBox.Value = true;
            app.StopButton = uibutton(app.UIFigure, 'state');
            app.StopButton.Text = 'Stop';
            app.RecordButton = uibutton(app.UIFigure, 'state');
            app.RecordButton.ValueChangedFcn = createCallbackFcn(app, @RecordButtonValueChanged, true);
            app.RecordButton.Text = 'Record';
            app.RecordAudioVideo_playSoundsLabel = uilabel(app.UIFigure);
            app.RecordAudioVideo_playSoundsLabel.Text = 'RecordAudioVideo_playSounds';
            app.version_Label = uilabel(app.UIFigure);
            app.version_Label.Text = 'ALC v1.2 2024-01-04';
            app.TimeelapsedEditFieldLabel = uilabel(app.UIFigure);
            app.TimeelapsedEditFieldLabel.Text = 'Time elapsed ';
            app.TimeElapsedEditField = uieditfield(app.UIFigure, 'text');
            app.TimeElapsedEditField.Editable = 'off';
            app.TimeElapsedEditField.Value = '00h : 00m : 00s';
            app.PlaySoundsPanel = uipanel(app.UIFigure);
            app.PlaySoundsPanel.Tooltip = {'Place sound files in the subfolder \Sounds with filenames ''sound1*.wav'', ''sound2*.wav'', etc.'};
            app.PlaySoundsPanel.Title = 'Play sounds';
            app.Sound1Button = uibutton(app.PlaySoundsPanel, 'push');
            app.Sound1Button.ButtonPushedFcn = createCallbackFcn(app, @Sound1ButtonPushed, true);
            app.Sound1Button.Text = 'Sound 1';
            app.Sound2Button = uibutton(app.PlaySoundsPanel, 'push');
            app.Sound2Button.ButtonPushedFcn = createCallbackFcn(app, @Sound2ButtonPushed, true);
            app.Sound2Button.Text = 'Sound 2';
            app.Sound3Button = uibutton(app.PlaySoundsPanel, 'push');
            app.Sound3Button.ButtonPushedFcn = createCallbackFcn(app, @Sound3ButtonPushed, true);
            app.Sound3Button.Text = 'Sound 3';
            app.Sound4Button = uibutton(app.PlaySoundsPanel, 'push');
            app.Sound4Button.ButtonPushedFcn = createCallbackFcn(app, @Sound4ButtonPushed, true);
            app.Sound4Button.Text = 'Sound 4';
            app.Sound5Button = uibutton(app.PlaySoundsPanel, 'push');
            app.Sound5Button.ButtonPushedFcn = createCallbackFcn(app, @Sound5ButtonPushed, true);
            app.Sound5Button.Text = 'Sound 5';
            app.Gain1EditField = uieditfield(app.PlaySoundsPanel, 'numeric');
            app.Gain1EditField.ValueDisplayFormat = '%.0f';
            app.Gain1EditField.ValueChangedFcn = createCallbackFcn(app, @Gain1EditFieldValueChanged, true);
            app.Gain2EditField = uieditfield(app.PlaySoundsPanel, 'numeric');
            app.Gain2EditField.ValueChangedFcn = createCallbackFcn(app, @Gain2EditFieldValueChanged, true);
            app.Gain3EditField = uieditfield(app.PlaySoundsPanel, 'numeric');
            app.Gain3EditField.ValueChangedFcn = createCallbackFcn(app, @Gain3EditFieldValueChanged, true);
            app.Gain4EditField = uieditfield(app.PlaySoundsPanel, 'numeric');
            app.Gain4EditField.ValueChangedFcn = createCallbackFcn(app, @Gain4EditFieldValueChanged, true);
            app.Gain5EditField = uieditfield(app.PlaySoundsPanel, 'numeric');
            app.Gain5EditField.ValueChangedFcn = createCallbackFcn(app, @Gain5EditFieldValueChanged, true);
            app.GaindBLabel = uilabel(app.PlaySoundsPanel);
            app.GaindBLabel.Text = {'Gain'; '(dB)'};
            app.PlayVisualStimuliPanel = uipanel(app.UIFigure);
            app.PlayVisualStimuliPanel.Tooltip = {'Place sound files in the subfolder \Sounds with filenames ''sound1*.wav'', ''sound2*.wav'', etc.'};
            app.PlayVisualStimuliPanel.Title = 'Play looming disc';
            app.discSize_init_deg_EditField = uieditfield(app.PlayVisualStimuliPanel, 'numeric');
            app.discSize_init_deg_EditField.ValueDisplayFormat = '%.1f';
            app.discSize_init_deg_EditField.ValueChangedFcn = createCallbackFcn(app, @discSize_init_deg_EditFieldValueChanged, true);
            app.discSize_init_deg_EditField.Value = 2.6;
            app.initialdegLabel = uilabel(app.PlayVisualStimuliPanel);
            app.initialdegLabel.Text = {'initial'; '(deg)'};
            app.enddegLabel = uilabel(app.PlayVisualStimuliPanel);
            app.enddegLabel.Text = {'end'; '(deg)'};
            app.discSize_end_deg_EditField = uieditfield(app.PlayVisualStimuliPanel, 'numeric');
            app.discSize_end_deg_EditField.Limits = [0 Inf];
            app.discSize_end_deg_EditField.ValueDisplayFormat = '%.1f';
            app.discSize_end_deg_EditField.ValueChangedFcn = createCallbackFcn(app, @discSize_end_deg_EditFieldValueChanged, true);
            app.discSize_end_deg_EditField.Value = 47.2;
            app.DiscsizeLabel = uilabel(app.PlayVisualStimuliPanel);
            app.DiscsizeLabel.Text = 'Disc size';
            app.ExpansionspeeddegsLabel = uilabel(app.PlayVisualStimuliPanel);
            app.ExpansionspeeddegsLabel.Text = {'Expansion'; 'speed'; '(deg/s)'};
            app.expansionSpeed_degps_EditField = uieditfield(app.PlayVisualStimuliPanel, 'numeric');
            app.expansionSpeed_degps_EditField.Limits = [0 Inf];
            app.expansionSpeed_degps_EditField.ValueDisplayFormat = '%.1f';
            app.expansionSpeed_degps_EditField.ValueChangedFcn = createCallbackFcn(app, @expansionSpeed_degps_EditFieldValueChanged, true);
            app.expansionSpeed_degps_EditField.Value = 11.2;
            app.DisccolorRGB01Label = uilabel(app.PlayVisualStimuliPanel);
            app.DisccolorRGB01Label.Text = {'Disc color'; 'RGB [0 1]'};
            app.discColor_EditField_R = uieditfield(app.PlayVisualStimuliPanel, 'numeric');
            app.discColor_EditField_R.Limits = [0 1];
            app.discColor_EditField_R.ValueDisplayFormat = '%.2g';
            app.discColor_EditField_R.ValueChangedFcn = createCallbackFcn(app, @discColor_EditField_RValueChanged, true);
            app.discColor_EditField_G = uieditfield(app.PlayVisualStimuliPanel, 'numeric');
            app.discColor_EditField_G.Limits = [0 1];
            app.discColor_EditField_G.ValueDisplayFormat = '%.2g';
            app.discColor_EditField_G.ValueChangedFcn = createCallbackFcn(app, @discColor_EditField_GValueChanged, true);
            app.discColor_EditField_B = uieditfield(app.PlayVisualStimuliPanel, 'numeric');
            app.discColor_EditField_B.Limits = [0 1];
            app.discColor_EditField_B.ValueDisplayFormat = '%.2g';
            app.discColor_EditField_B.ValueChangedFcn = createCallbackFcn(app, @discColor_EditField_BValueChanged, true);
            app.Backgroundlumin01Label = uilabel(app.PlayVisualStimuliPanel);
            app.Backgroundlumin01Label.Text = {'Background'; ' lumin. [0 1]'};
            app.BackgroundLuminance_EditField = uieditfield(app.PlayVisualStimuliPanel, 'numeric');
            app.BackgroundLuminance_EditField.Limits = [0 1];
            app.BackgroundLuminance_EditField.ValueDisplayFormat = '%.2g';
            app.BackgroundLuminance_EditField.ValueChangedFcn = createCallbackFcn(app, @BackgroundLuminance_EditFieldValueChanged, true);
            app.BackgroundLuminance_EditField.Value = 0.5;
            app.PoststimintervalsLabel = uilabel(app.PlayVisualStimuliPanel);
            app.PoststimintervalsLabel.Tooltip = {'Once disc reached final size, wait this interval before displaying only the background screen'};
            app.PoststimintervalsLabel.Text = {'Post-stim'; 'interval'; '(s)'};
            app.post_stim_interval_sec_EditField = uieditfield(app.PlayVisualStimuliPanel, 'numeric');
            app.post_stim_interval_sec_EditField.Limits = [0 1];
            app.post_stim_interval_sec_EditField.ValueDisplayFormat = '%.2g';
            app.post_stim_interval_sec_EditField.ValueChangedFcn = createCallbackFcn(app, @post_stim_interval_sec_EditFieldValueChanged, true);
            app.post_stim_interval_sec_EditField.Tooltip = {'Once disc reached final size, wait this interval before displaying only the background screen'};
            app.post_stim_interval_sec_EditField.Value = 1;
            app.LoomingDiscButton = uibutton(app.PlayVisualStimuliPanel, 'push');
            app.LoomingDiscButton.ButtonPushedFcn = createCallbackFcn(app, @LoomingDiscButtonPushed, true);
            app.LoomingDiscButton.Text = {'Looming'; 'disc'};
            app.ArduinoLedPanel = uipanel(app.UIFigure);
            app.ArduinoLedPanel.Title = 'Arduino (Led)';
            app.ConnectArduino_Button = uibutton(app.ArduinoLedPanel, 'push');
            app.ConnectArduino_Button.ButtonPushedFcn = createCallbackFcn(app, @ConnectArduino_ButtonPushed, true);
            app.ConnectArduino_Button.Text = 'Connect Arduino';
            app.ArduinoStatus_Lamp = uilamp(app.ArduinoLedPanel);
            app.DisconnectArduino_Button = uibutton(app.ArduinoLedPanel, 'push');
            app.DisconnectArduino_Button.ButtonPushedFcn = createCallbackFcn(app, @DisconnectArduino_ButtonPushed, true);
            app.DisconnectArduino_Button.Text = 'Disconnect';
            app.ArduinoStatus_Label = uilabel(app.ArduinoLedPanel);
            app.ArduinoStatus_Label.Text = 'Arduino status';
            app.PortEditFieldLabel = uilabel(app.ArduinoLedPanel);
            app.PortEditFieldLabel.Text = 'Port';
            app.ArduinoPort_EditField = uieditfield(app.ArduinoLedPanel, 'text');
            app.ArduinoPort_EditField.Value = 'COM5';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RecordAudioVideo_playSounds

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end